classdef ImagePostProcessingDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageCorrectionDialogUIFigure  matlab.ui.Figure
        MainMenuPanel                  matlab.ui.container.Panel
        CurrentSettingsLabel           matlab.ui.control.Label
        FilenameCurrentSettingsLabel   matlab.ui.control.Label
        SaveSettingsButton             matlab.ui.control.Button
        LoadSettingsButton             matlab.ui.control.Button
        ApplyDCCheckBox                matlab.ui.control.CheckBox
        ApplyGCCheckBox                matlab.ui.control.CheckBox
        ApplyFFCheckBox                matlab.ui.control.CheckBox
        CorrectImagesButton            matlab.ui.control.Button
        FlatfieldImagePanel            matlab.ui.container.Panel
        CurrentFlatfieldimageLabel     matlab.ui.control.Label
        ChangeFlatfieldImageButton     matlab.ui.control.Button
        FilenameFlatFieldLabel         matlab.ui.control.Label
        ObjectiveDistortionSettingsPanel  matlab.ui.container.Panel
        StyleDropDown                  matlab.ui.control.DropDown
        StyleDropDownLabel             matlab.ui.control.Label
        CurrentSettingsLabel_2         matlab.ui.control.Label
        FilenameGerometricDistortionLabel  matlab.ui.control.Label
        ChangeObjDistortionButton      matlab.ui.control.Button
        GridDistortionSettingsPanel    matlab.ui.container.Panel
        ShowFittingFigureCheckBox      matlab.ui.control.CheckBox
        OptimizePlaneButton            matlab.ui.control.Button
        OptimizeGridCheckBox           matlab.ui.control.CheckBox
        xEditField_2Label_3            matlab.ui.control.Label
        OutputShiftYEditField          matlab.ui.control.NumericEditField
        xEditField_2Label_2            matlab.ui.control.Label
        OutputShiftXEditField          matlab.ui.control.NumericEditField
        ShiftOutputLabel               matlab.ui.control.Label
        TransformationMethodDropDown   matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        ResetSettingsButton            matlab.ui.control.Button
        GridCurrentLabel               matlab.ui.control.Label
        CorrectionTestButton           matlab.ui.control.Button
        GridDisplayNextPlane           matlab.ui.control.Button
        GridDisplayPrevPlane           matlab.ui.control.Button
        GridDisplayLastPlane           matlab.ui.control.Button
        GridDisplayFirstPlane          matlab.ui.control.Button
        PopulatetherestButton          matlab.ui.control.Button
        PopulateFirstPlaneButton       matlab.ui.control.Button
        SetupGridPointsButton          matlab.ui.control.Button
        InitialiseButton               matlab.ui.control.Button
        GridPlaneIntervalEditField     matlab.ui.control.NumericEditField
        SlidesbetweensetsLabel         matlab.ui.control.Label
        GridSizeZEditField             matlab.ui.control.NumericEditField
        NumberofplanesLabel            matlab.ui.control.Label
        GridPlaneAveragingEditField    matlab.ui.control.NumericEditField
        AveragingEditFieldLabel        matlab.ui.control.Label
        GridPlaneEndEditField          matlab.ui.control.NumericEditField
        EndLabel                       matlab.ui.control.Label
        GridPlaneStartEditField        matlab.ui.control.NumericEditField
        StartLabel                     matlab.ui.control.Label
        SlideLabel_2                   matlab.ui.control.Label
        GridSizeYEditField             matlab.ui.control.NumericEditField
        xEditField_2Label              matlab.ui.control.Label
        GridSizeXEditField             matlab.ui.control.NumericEditField
        ChangeRefImageButton           matlab.ui.control.Button
        FilenameGridRefLabel           matlab.ui.control.Label
        GridImageLabel                 matlab.ui.control.Label
        GridSizeLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        grid_SizeX =10; %number of grid points in X direction
        grid_SizeY =10; %number of grid points in Y direction
        grid_SizeZ =8;  %number of grid planes
 
        grid_PlaneStart     =400;    %slide # for first grid plane
        grid_PlaneAveraging =10;      %number of slide to average to represent a plane
        grid_PlaneInterval  =50;     %number of slide between each plane

        img_SizeZ           %number of slide in the raw image 

        ImgBufReady = false;
        grid_PlaneImgBuf
        ref_Image

        grid_Position;       %positions of grid points (index(x,y), pos(x,y), z)
        grid_Info ={};       %other info about the grid points {x,y,z} 

        fig_GridHandle = {};  %grid points handle in fig object
        
        grid_Init_Position;
        fig_Grid_InitHandle = {};
        fig_Grid_InitHandleReady = false;

        grid_PlaneCurrent = 1
        grid_SettingStatus = 0;  %differnt level of ready...

        filename_GridRef = 'none';              %grid reference image 
        filename_Flatfield ='none';             %flatfield image 
        filename_CurrentSettings = 'none';      %master settings 
        filename_GeometricDistortion = 'none';  %geometric distortion setting 

        image_OutputShiftX=0
        image_OutputShiftY=0

        workingDirectory;                       %save working directory
        parentApp;
        rotationOn=true;                        %rotate the local fit offset back into image (x,y) frame;
                                                 %must be true whenever dx,dy (grid spacing vector) is not
                                                 %axis-aligned, which is normal for a rotated/distorted grid
        fitOneGrid=true;                        %fit one or three grid lines together
        fitRange=6;                             %maximum search area between grid points
        debug=1;                                %debug mode
        debugPlot=false;                        %plot fit results in debug mode; synced from
                                                 %ShowFittingFigureCheckBox, off by default since it
                                                 %redraws on every single-point fit during batch population

        grid_LatticeModel = {};                 %per-plane auto-detected (or manual) global lattice model:
                                                 %spacing/orientation/phase used to predict every crossing's
                                                 %position independently, instead of neighbor propagation
        grid_SurfaceModel = {};                 %per-plane robust global 2D polynomial surface fit (sfX,sfY)
        grid_AutoDetectEnabled = true;          %use automatic FFT-based lattice detection when possible
        grid_RefinementPasses = 2;              %number of predict->refine->smooth passes per plane
                                                 %(whether to optimize at all is controlled by the
                                                 %OptimizeGridCheckBox UI control, not a private property,
                                                 %matching the ApplyFFCheckBox/ApplyGCCheckBox convention)
    end
    
    methods (Access = private)
        
        function ChangeFileNameLabel(~, selectedLabel, new_Filename)
            if(length(new_Filename)>35)
                selectedLabel.Text=['...' new_Filename(end-35:end)];
            else
                selectedLabel.Text=new_Filename;
            end
        end
        
        function LoadReferenceImage(app)
            filename=app.filename_GridRef;
            file_info = imfinfo(filename);
            app.img_SizeZ = numel(file_info);
            
            Height=file_info.Height;
            Width=file_info.Width;
            
            rawImage=zeros(Height,Width,app.grid_PlaneAveraging);
            app.grid_PlaneImgBuf=zeros(Height,Width,app.grid_SizeZ);

            fig=app.ImageCorrectionDialogUIFigure;
            title='Loading Grid Reference Image';
            dlg=uiprogressdlg(fig, 'Title', title, 'Cancelable','on');
            
            for index=1:app.grid_SizeZ
                msg=sprintf('Please wait');
                progress_bar=index/(app.grid_SizeZ);
                dlg.Value=progress_bar;
                dlg.Message=msg;
                
                for k=1:app.grid_PlaneAveraging
                    rawImage(:,:,k) = imread(filename, GridPlaneSlideNumber(app, index)+k);
                end
                tempImage=sum(rawImage,3);
                tempImage=squeeze(tempImage);
                tempImage=insertText(tempImage, [80 100], "Test", "TextColor","white", "FontSize", 30);
                tempImage=tempImage(:,:,1);
                app.grid_PlaneImgBuf(:,:,index)=tempImage;
                if dlg.CancelRequested
                    break
                end
            end
            close(dlg);

            app.ImgBufReady=true;
        end
        
        
        function GridPositionMoved(app, src)
            selected=str2double(src{1}.Tag);
            app.grid_Position(selected,:, app.grid_PlaneCurrent)=app.fig_GridHandle{selected}.handle.Position;
            app.grid_Info{selected, app.grid_PlaneCurrent}.status=1;            
%            app.grid_SettingStatus=true;
        end
        
        function GridInitPositionMoved(app, src)
            selected=str2double(src{1}.Tag);
            app.grid_Init_Position(selected,:)=app.fig_Grid_InitHandle{selected}.handle.Position;
        end

        function GridPositionClicked(app,src)
            uiresume();
            selected=str2double(src{1}.Tag);
            app.grid_Position(selected,:, app.grid_PlaneCurrent)=app.fig_GridHandle{selected}.handle.Position;
        end
        
        function DistortionCorrectionTest(app)

            ChangeRefImagePlane(app);
            
            [RA, worldPoints, squareSize]=GridTransformationInitialize(app, app.grid_PlaneCurrent);

            grid_Position_Current=app.grid_Position(:,:,app.grid_PlaneCurrent);            
            transformationMethod=str2double(app.TransformationMethodDropDown.Value);
            switch(transformationMethod)
                case 1
                    tform = fitgeotrans(grid_Position_Current,worldPoints,"affine");
                case 2
                    tform = fitgeotrans(grid_Position_Current,worldPoints,"projective");
                case 3
                    tform = fitgeotrans(grid_Position_Current,worldPoints,"polynomial", 3);
                case 4
                    tform = fitgeotrans(grid_Position_Current,worldPoints,"polynomial", 4);
            end

            if(transformationMethod<5)
                correctedImage=imwarp(app.ref_Image, tform, 'interp', 'cubic', 'Outputview', RA); %nearest, linear or cubic
            else
                %code for piecewise correction
                %m,n position in mini maps; i,j positions in world maps

                %find output position in the large worldmap
%                firstWorldPoint=[grid_Position_Current(1,1)/RA.PixelExtentInWorldX grid_Position_Current(1,2)/RA.PixelExtentInWorldY];

                miniOutputPosition=zeros(9,2);
                miniGridPosition=zeros(9,2);

                mini_ImageSize=2*squareSize;
                RA_mini = imref2d([mini_ImageSize mini_ImageSize]);
    
                miniWorldPoints = generateCheckerboardPoints([4 4], squareSize);
                miniWorldPoints = flip(miniWorldPoints,2);  %exchange x and y axis in world points
                
                iL=floor((app.grid_SizeX-1)/2);
                jL=floor((app.grid_SizeY-1)/2);

                correctedImage=zeros(mini_ImageSize*jL, mini_ImageSize*iL);
                
                for j=1:jL
                    for i=1:iL
                        i_current=1+(i-1)*2;
                        j_current=1+(j-1)*2;
                        for n=1:3
                            for m=1:3
                                index_mini=m+(n-1)*3;
                                index_world=i_current+m-1+(j_current+n-2)*app.grid_SizeX;
                                miniOutputPosition(index_mini,:)=worldPoints(index_world,:);
                                miniGridPosition(index_mini,:)=grid_Position_Current(index_world,:);
                            end
                        end
    
                        index=i+(j-1)*iL;
                        app.ref_Image=insertText(app.ref_Image, [miniGridPosition(1,1)+10 miniGridPosition(1,2)+10], index, "TextColor","white", "FontSize", 20, 'BoxOpacity',0);
                        app.ref_Image=app.ref_Image(:,:,1);
    
                        xOutput = [miniOutputPosition(1,2) miniOutputPosition(end,2)];
                        yOutput = [miniOutputPosition(1,1) miniOutputPosition(end,1)];

                        switch(transformationMethod)
                            case 5
                                tform = fitgeotrans(miniGridPosition,miniWorldPoints,"affine");
                            case 6
                                tform = fitgeotrans(miniGridPosition,miniWorldPoints,"projective");
                            case 7
                                tform = fitgeotrans(miniGridPosition,miniWorldPoints,"polynomial", 2);

                        end

                        correctedminiImage=imwarp(app.ref_Image, tform, 'interp', 'cubic', 'Outputview', RA_mini); %nearest, linear or cubic
                        correctedImage((xOutput(1)+1):xOutput(2), (yOutput(1)+1):yOutput(2))=correctedminiImage;
                    end
                end
            end

            figHandle=figure(2);
            set(figHandle, 'NumberTitle', 'off', 'Name', sprintf('Grid Correction Test on Plane #%d', app.grid_PlaneCurrent));
            
            subplot(1,2,1);
            imshow(app.ref_Image, [0 3]);
            title('Before Correction');
            subplot(1,2,2);
            imshow(correctedImage, [0 3]);
            title('After Correction');

            %check intensity change
%            intensity_original=sum(app.ref_Image, "all");
%            intensity_corrected=sum(correctedImage, "all");
%            intensity_original
%            intensity_corrected
           
        end
        
        function LoadGridSettings(app, filename)
            load(filename, 'Grid_Save');
            app.Filename_GridDistortion=filename;
            app.grid_SizeX=size(Grid_Save,1);
            app.grid_SizeY=size(Grid_Save,2);
            app.GridSizeValue.Text=[num2str(app.grid_SizeX) ' x ' num2str(app.grid_SizeY)];
            Grid_Save=reshape(Grid_Save, size(Grid_Save,1)*size(Grid_Save,2), 2,4);
            app.grid_Position=Grid_Save;
            for i=1:4
                if(app.grid_Position(1,1, i)~=-1)
                    switch(i)
                        case 1
                            app.Grid1Clear.Enable=true;
                            app.Grid1Test.Enable=true;
                        case 2
                            app.Grid2Clear.Enable=true;
                            app.Grid2Test.Enable=true;
                        case 3
                            app.Grid3Clear.Enable=true;
                            app.Grid3Test.Enable=true;
                        case 4
                            app.Grid4Clear.Enable=true;
                            app.Grid4Test.Enable=true;
                    end
                end
            end
        end

        function [new_pos,status,fitQuality] = ...
                GridPositionOptimize(app,pos,dx,dy)
        
            new_pos = pos;
            status = 0;
        
            fitQuality.rmseX = inf;
            fitQuality.rmseY = inf;
        
            [profileX,profileY,profileStatus] = ...
                GridProfile(app,pos,dx,dy);
        
            if profileStatus ~= 1
                status = profileStatus;
                return;
            end
        
            [offsetX,fitOKX,rmseX] = ...
                GridFitting(app,profileX,1);
        
            [offsetY,fitOKY,rmseY] = ...
                GridFitting(app,profileY,2);
        
            fitQuality.rmseX = rmseX;
            fitQuality.rmseY = rmseY;
        
            if ~fitOKX || ~fitOKY
                status = -2;
                return;
            end
        
            if abs(offsetX) > app.fitRange || ...
               abs(offsetY) > app.fitRange
                status = -3;
                return;
            end
        
            if app.rotationOn
                gridSpacing = hypot(dx,dy);
        
                ux = dx/gridSpacing;
                uy = dy/gridSpacing;
        
                vx = -uy;
                vy =  ux;
        
                imageOffset = ...
                    offsetX*[ux uy] + ...
                    offsetY*[vx vy];
        
                new_pos = pos + imageOffset;
            else
                new_pos = pos + [offsetX offsetY];
            end
        
            status = 1;
        end        
        
        function [profileX, profileY, status] = ...
                GridProfile(app,pos,gridDx,gridDy)
        
            profileX = [];
            profileY = [];
            status = -1;
        
            imageData = double(app.ref_Image);
            [imageHeight,imageWidth] = size(imageData);
        
            gridSpacing = hypot(gridDx,gridDy);
        
            if ~isfinite(gridSpacing) || gridSpacing < 2
                status = -2;
                return;
            end
        
            % Current fitting function only supports the single-crossing mode.
            if ~app.fitOneGrid
                status = -3;
                return;
            end
        
            halfWidth = max(app.fitRange+2, ...
                            floor(0.6*gridSpacing));
        
            % Unit vector along one family of grid bars.
            ux = gridDx/gridSpacing;
            uy = gridDy/gridSpacing;
        
            % Orthogonal unit vector.
            vx = -uy;
            vy =  ux;
        
            localAxis = -halfWidth:halfWidth;
            [localX,localY] = meshgrid(localAxis,localAxis);
        
            % Map the locally aligned coordinates into image coordinates.
            queryX = pos(1) + localX*ux + localY*vx;
            queryY = pos(2) + localX*uy + localY*vy;
        
            if min(queryX,[],"all") < 1 || ...
               max(queryX,[],"all") > imageWidth || ...
               min(queryY,[],"all") < 1 || ...
               max(queryY,[],"all") > imageHeight
                status = -1;
                return;
            end
        
            patch = interp2( ...
                imageData,queryX,queryY,"linear",NaN);
        
            if any(~isfinite(patch),"all")
                status = -1;
                return;
            end
        
            % Use the mean instead of the sum so the intensity scale does not
            % depend on the patch dimensions.
            profileX = mean(patch,1);
            profileY = mean(patch,2).';
        
            status = 1;
        
            if app.debug && app.debugPlot
                figure(2);
                clf;
        
                subplot(1,3,1);
                imagesc(localAxis,localAxis,patch);
                axis image;
                title("Aligned local patch");
        
                subplot(1,3,2);
                plot(localAxis,profileX);
                title("Horizontal profile");
        
                subplot(1,3,3);
                plot(localAxis,profileY);
                title("Vertical profile");
            end
        end

        function [offset,fitOK,fitRMSE] = ...
                GridFitting(app,lprofile,num)
        
            offset = 0;
            fitOK = false;
            fitRMSE = inf;
        
            profile = double(lprofile(:).');
        
            if isempty(profile) || ...
               any(~isfinite(profile)) || ...
               max(profile) - min(profile) <= eps
                return;
            end
        
            L = numel(profile);
            xPos = -(L-1)/2:(L-1)/2;
        
            % Normalize to make fitting bounds less dependent on crop size
            % and image intensity.
            baseline = median(profile);
            scale = max(abs(profile-baseline));
        
            if scale <= eps
                return;
            end
        
            profileNorm = (profile-baseline)/scale;
        
            model = @(p,x) ...
                p(1) ...
                - p(3)*exp(-0.5*((x-p(2))/p(4)).^2) ...
                + p(5)*x;
        
            % p = [background, center, depth, width, slope]
            lb = [
                -1, ...
                -app.fitRange, ...
                 0.02, ...
                 1, ...
                -0.2
            ];
        
            ub = [
                 1, ...
                 app.fitRange, ...
                 2, ...
                 max(2,app.fitRange), ...
                 0.2
            ];
        
            [~,minimumIndex] = min(profileNorm);
            initialCenter = xPos(minimumIndex);
        
            initialCenter = max( ...
                -app.fitRange, ...
                min(app.fitRange,initialCenter));
        
            x0 = [
                median(profileNorm), ...
                initialCenter, ...
                max(0.1,median(profileNorm)-min(profileNorm)), ...
                3, ...
                0
            ];
        
            options = optimoptions( ...
                "lsqcurvefit", ...
                "Algorithm","trust-region-reflective", ...
                "Display","off");
        
            try
                [fitParameters,resnorm,~,exitflag] = ...
                    lsqcurvefit( ...
                        model,x0,xPos,profileNorm,lb,ub,options);
            catch
                return;
            end
        
            offset = fitParameters(2);
            fitRMSE = sqrt(resnorm/L);
        
            centerAtLimit = ...
                abs(abs(offset)-app.fitRange) < 0.05;
        
            sufficientDepth = fitParameters(3) > 0.05;
            reasonableWidth = ...
                fitParameters(4) > 1 && ...
                fitParameters(4) < app.fitRange;
        
            fitOK = ...
                exitflag > 0 && ...
                isfinite(offset) && ...
                ~centerAtLimit && ...
                sufficientDepth && ...
                reasonableWidth;
        
            if app.debug && app.debugPlot
                figure(2);
                subplot(1,3,num+1);
                hold on;
                plot(xPos,model(fitParameters,xPos));
                hold off;
            end
        end        
        
        function [dx, dy] = GridPositionFindOffset(~, pos1, pos2)
            dx=pos2(1)-pos1(1);
            dy=pos2(2)-pos1(2);
        end
        
        function FlattenImage(app)
            app.ref_Image(app.ref_Image>3)=3;
        end
        
        function SetGridPlaneParameter(app)
             app.GridPlaneEndEditField.Value=app.grid_PlaneStart+(app.grid_SizeZ-1)*app.grid_PlaneInterval;
        end
        
        function GridInitialization(app)
            app.grid_SizeX=app.GridSizeXEditField.Value;
            app.grid_SizeY=app.GridSizeYEditField.Value;
            app.grid_SizeZ=app.GridSizeZEditField.Value;
            GridSize=app.grid_SizeX*app.grid_SizeY;

            app.grid_Position=zeros(GridSize,2,app.grid_SizeZ); %index, xy, z
            
            for index=1:GridSize
                for z=1:app.grid_SizeZ
                    app.grid_Position(index,:,z)=[-1 -1];
                end
            end
            
            app.grid_Info{GridSize, app.grid_SizeZ}=[];

            for index=1:GridSize
                for z=1:app.grid_SizeZ
                    app.grid_Info{index,z}.Status=-1;
                end
            end

        end
        
        function GridChangeDisplayPlane(app)
            ChangeRefImagePlane(app);
            
            figHandle=figure(1);
            set(figHandle, 'NumberTitle', 'off', 'Name', sprintf('Grid Plane #%d', app.grid_PlaneCurrent));

            imshow(app.ref_Image, [0 3]);
            for grid_y=1:app.grid_SizeY
                for grid_x=1:app.grid_SizeX
                    index=(grid_y-1)*app.grid_SizeX+grid_x;
                    pos=app.grid_Position(index, :, app.grid_PlaneCurrent);
                    info = app.grid_Info{index, app.grid_PlaneCurrent};
                    if isfield(info,'source') && strcmp(info.source,'z-trend')
                        % Corrected via cross-plane (Z) correlation because
                        % this point/plane wasn't trustworthy on its own --
                        % shown distinctly so it's clear which points were
                        % filled in this way, versus directly measured.
                        app.fig_GridHandle{index}.handle = drawpoint('Position',pos, 'Color', [0.2 0.8 1]);
                    else
                        switch(info.status)
                            case 1
                                app.fig_GridHandle{index}.handle = drawpoint('Position',pos);
                            case 2
                                app.fig_GridHandle{index}.handle = drawpoint('Position',pos, 'Color', [1 0.55 0.75]);
                            otherwise
                                app.fig_GridHandle{index}.handle = drawpoint('Position',pos, 'Color', [1 0.2 0.75]);
                        end
                    end
                    app.fig_GridHandle{index}.handle.Tag=int2str(index);
                    addlistener(app.fig_GridHandle{index}.handle,'ROIMoved', @(varargin)GridPositionMoved(app, varargin));
                end
            end
        end
        
        function slide_num=GridPlaneSlideNumber(app, plane)
            slide_num=app.grid_PlaneStart+(plane-1)*app.grid_PlaneInterval;
        end


        function [RA, worldPoints, squareSize] = GridTransformationInitialize(app, gridPlane)

            grid_Position_Current=app.grid_Position(:,:,gridPlane);
                
            squareSize = norm(grid_Position_Current(1,:)-grid_Position_Current(app.grid_SizeX,:))/(app.grid_SizeX-1);
            squareSize = ceil(squareSize); 

            worldPoints = generateCheckerboardPoints([app.grid_SizeX+1 app.grid_SizeY+1], squareSize);
            worldPoints = flip(worldPoints,2);  %exchange x and y axis in world points
                
%                dy=(grid_Position_Current(app.grid_SizeY,2)-grid_Position_Current(1,2));
%                if(dy==0)
%                    angle=0;
%                else
%                    angle=atan((grid_Position_Current(app.grid_SizeY,1)-grid_Position_Current(1,1))/(grid_Position_Current(app.grid_SizeY,2)-grid_Position_Current(1,2)));
%                end

%                if(angle<0.1)
%                    angle = 0.1;
%                end
                
%                outputLength=floor(max(size(app.ref_Image))/cos(angle));
%                sizeOutput = [outputLength outputLength];

            sizeInput = size(app.ref_Image);
            sizeOutput = size(app.ref_Image);
                
%                topMargin=grid_Position_Current(1,2);
%                bottomMargin=sizeInput(2)-topMargin;
                
%                leftMargin=grid_Position_Current(1,1);
%                rightMargin=sizeInput(1)-leftMargin;
                
                %try to keep output image centered vertically similarly to the original image

            topMargin=grid_Position_Current(1,2);
            bottomMargin=sizeInput(2)-topMargin;
            
            leftMargin=grid_Position_Current(1,1);
            rightMargin=sizeInput(1)-leftMargin;

            yWorldLimits = [-topMargin+app.image_OutputShiftY bottomMargin+app.image_OutputShiftY];
            xWorldLimits = [-leftMargin-app.image_OutputShiftX rightMargin-app.image_OutputShiftX];
            RA = imref2d(flip(sizeOutput),xWorldLimits,yWorldLimits);
%                RA = imref2d(sizeOutput, );

        end


        function GridSettingStatusChange(app, status)
            %0 - blank state, ready to change settings
            %1 - initilzation and image is loaded, not more setting
            %changes besides average..
            %2 - first plane is done
            %3 - all planes are done, ready for full correction
            app.grid_SettingStatus=status;
            switch(status)
                case 0
                    app.GridPlaneStartEditField.Enable=true;
                    app.GridPlaneAveragingEditField.Enable=true;
                    app.GridPlaneIntervalEditField.Enable=true;
                    app.GridSizeXEditField.Enable=true;
                    app.GridSizeYEditField.Enable=true;
                    app.GridSizeZEditField.Enable=true;
                    if(strcmp(app.filename_GridRef,'none')==true)
                        app.InitialiseButton.Enable=false;
                    else
                        app.InitialiseButton.Enable=true;
                    end
                    app.SetupGridPointsButton.Enable=false;
                    app.PopulateFirstPlaneButton.Enable=false;
                    app.PopulatetherestButton.Enable=false;
                    app.GridDisplayPrevPlane.Enable=false;
                    app.GridDisplayNextPlane.Enable=false;
                    app.GridDisplayFirstPlane.Enable=false;
                    app.GridDisplayLastPlane.Enable=false;
                    app.CorrectionTestButton.Enable=false;
                    app.OptimizePlaneButton.Enable=false;
                    app.ApplyGCCheckBox.Enable=false;
%                    app.CorrectImagesButton.Enable=false;
                
                case 1
                    app.GridPlaneStartEditField.Enable=false;
                    app.GridPlaneAveragingEditField.Enable=false;
                    app.GridPlaneIntervalEditField.Enable=false;
                    app.GridSizeXEditField.Enable=false;
                    app.GridSizeYEditField.Enable=false;
                    app.GridSizeZEditField.Enable=false;
                    app.InitialiseButton.Enable=true;
                    app.SetupGridPointsButton.Enable=true;
                    app.PopulateFirstPlaneButton.Enable=false;
                    app.PopulatetherestButton.Enable=false;
                    app.GridDisplayPrevPlane.Enable=false;
                    app.GridDisplayNextPlane.Enable=false;
                    app.GridDisplayFirstPlane.Enable=false;
                    app.GridDisplayLastPlane.Enable=false;
                    app.CorrectionTestButton.Enable=false;
                    app.OptimizePlaneButton.Enable=false;
                    app.ApplyGCCheckBox.Enable=false;
%                    app.CorrectImagesButton.Enable=false;
                case 2
                    app.GridPlaneStartEditField.Enable=false;
                    app.GridPlaneAveragingEditField.Enable=false;
                    app.GridPlaneIntervalEditField.Enable=false;
                    app.GridSizeXEditField.Enable=false;
                    app.GridSizeYEditField.Enable=false;
                    app.GridSizeZEditField.Enable=false;
                    app.InitialiseButton.Enable=true;
                    app.SetupGridPointsButton.Enable=true;
                    app.PopulateFirstPlaneButton.Enable=true;
                    app.PopulatetherestButton.Enable=false;
                    app.GridDisplayPrevPlane.Enable=false;
                    app.GridDisplayNextPlane.Enable=false;
                    app.GridDisplayFirstPlane.Enable=false;
                    app.GridDisplayLastPlane.Enable=false;
                    app.CorrectionTestButton.Enable=false;
                    app.OptimizePlaneButton.Enable=false;
                    app.ApplyGCCheckBox.Enable=false;
%                    app.CorrectImagesButton.Enable=false;
                case 3
                    app.GridPlaneStartEditField.Enable=false;
                    app.GridPlaneAveragingEditField.Enable=false;
                    app.GridPlaneIntervalEditField.Enable=false;
                    app.GridSizeXEditField.Enable=false;
                    app.GridSizeYEditField.Enable=false;
                    app.GridSizeZEditField.Enable=false;
                    app.InitialiseButton.Enable=true;
                    app.SetupGridPointsButton.Enable=true;
                    app.PopulateFirstPlaneButton.Enable=true;
                    app.PopulatetherestButton.Enable=true;
                    app.GridDisplayPrevPlane.Enable=false;
                    app.GridDisplayNextPlane.Enable=false;
                    app.GridDisplayFirstPlane.Enable=false;
                    app.GridDisplayLastPlane.Enable=false;
                    app.CorrectionTestButton.Enable=true;
                    app.OptimizePlaneButton.Enable=true;
                    app.ApplyGCCheckBox.Enable=false;
%                    app.CorrectImagesButton.Enable=false;
                    
                case 4
                    app.GridPlaneStartEditField.Enable=false;
                    app.GridPlaneAveragingEditField.Enable=false;
                    app.GridPlaneIntervalEditField.Enable=false;
                    app.GridSizeXEditField.Enable=false;
                    app.GridSizeYEditField.Enable=false;
                    app.GridSizeZEditField.Enable=false;
                    app.InitialiseButton.Enable=true;
                    app.SetupGridPointsButton.Enable=true;
                    app.PopulateFirstPlaneButton.Enable=true;
                    app.PopulatetherestButton.Enable=true;
                    app.GridDisplayPrevPlane.Enable=true;
                    app.GridDisplayNextPlane.Enable=true;
                    app.GridDisplayFirstPlane.Enable=true;
                    app.GridDisplayLastPlane.Enable=true;
                    app.CorrectionTestButton.Enable=true;
                    app.OptimizePlaneButton.Enable=true;
                    app.ApplyGCCheckBox.Enable=true;
%                    app.CorrectImagesButton.Enable=true;
            end

        end
        
        function FindGridSpacing(app)
            %get positions and remove the points
            P1=app.grid_Init_Position(1,:);
            P2=app.grid_Init_Position(2,:);
            P3=app.grid_Init_Position(3,:);
            P4=app.grid_Init_Position(4,:);
            
            %sort the points to get 4 corners of a quadrilateral
            Array_1 = [P1;P2;P3;P4];
            order = convhull(Array_1);
            %assert(numel(order) == 5, 'Points do not form a convex quadrilateral');
            orderedpoints = Array_1(order(1:4), :);
            
            %Sort out the order of the corners, top left corner should
            %be first
            center_x=sum(orderedpoints(:,1))/4;
            center_y=sum(orderedpoints(:,2))/4;
            angles=zeros(1,4);
            for index=1:4
                angles(index)=atan2((orderedpoints(index,2)-center_y),(orderedpoints(index,1)-center_x));
                orderedpoints(index,:);
            end
            
            [~,I]=min(angles);
            orderedpoints=circshift(orderedpoints, [1-I 0]);
            
            %Find angle and distance for horizontal and vertical grid points
            pos1=[orderedpoints(1,1) orderedpoints(1,2)];
            pos2=[orderedpoints(2,1) orderedpoints(2,2)];
            [dx, dy]=GridPositionFindOffset(app, pos1, pos2);

            %optimze initial positions....
            pos=[orderedpoints(1,1) orderedpoints(1,2)];

            trial=3;
            while (trial>0)
                trial=trial-1;
                [new_pos, status]=GridPositionOptimize(app, pos, dx, dy);
                pos=new_pos;
                if(status==1)
                    break;
                end
            end

            app.grid_Position(1,:,1)=pos;
            orderedpoints(1,1)=pos(1);
            orderedpoints(1,2)=pos(2);

            pos=[orderedpoints(2,1) orderedpoints(2,2)];
            trial=3;
            while (trial>0)
                trial=trial-1;
                [new_pos, status]=GridPositionOptimize(app, pos, dx, dy);
                pos=new_pos;
                if(status==1)
                    break;
                end
            end
            app.grid_Position(2,:,1)=pos;
            orderedpoints(2,1)=pos(1);
            orderedpoints(2,2)=pos(2);

            pos=[orderedpoints(4,1) orderedpoints(4,2)];
            trial=3;
            while (trial>0)
                trial=trial-1;
                [new_pos, status]=GridPositionOptimize(app, pos, dx, dy);
                pos=new_pos;
                if(status==1)
                    break;
                end
            end
            orderedpoints(4,1)=pos(1);
            orderedpoints(4,2)=pos(2);     
            app.grid_Position(app.grid_SizeX+1,:,1)=pos;
            

            pos1=[orderedpoints(1,1) orderedpoints(1,2)];
            pos2=[orderedpoints(2,1) orderedpoints(2,2)];
            [xdx, xdy]=GridPositionFindOffset(app, pos1, pos2);
            pos2=[orderedpoints(4,1) orderedpoints(4,2)];
            [ydx, ydy]=GridPositionFindOffset(app, pos1, pos2);

            for index=1:app.grid_SizeX*app.grid_SizeY
                for z=1:app.grid_SizeZ
                    app.grid_Info{index,z}.xdx=xdx;
                    app.grid_Info{index,z}.xdy=xdy;
                    app.grid_Info{index,z}.ydx=ydx;
                    app.grid_Info{index,z}.ydy=ydy;
                    app.grid_Info{index,z}.zdx=0;
                    app.grid_Info{index,z}.zdy=0;
                end
            end

            app.grid_Init_Position(1,:)=[orderedpoints(1,1) orderedpoints(1,2)];
            app.grid_Init_Position(2,:)=[orderedpoints(2,1) orderedpoints(2,2)];
            app.grid_Init_Position(3,:)=[orderedpoints(2,1) orderedpoints(4,2)];
            app.grid_Init_Position(4,:)=[orderedpoints(4,1) orderedpoints(4,2)];
            
        end
        
        
        function ChangeRefImagePlane(app)
            if(app.ImgBufReady==false)
                LoadReferenceImage(app);
            end
            app.ref_Image=app.grid_PlaneImgBuf(:,:,app.grid_PlaneCurrent);
            FlattenImage(app);


        end

        %% --- Global-lattice grid detection (replaces neighbor propagation) ---
        %
        % The functions below predict every grid crossing's position from a
        % single global lattice model (spacing/orientation/phase), refine each
        % one INDEPENDENTLY using the existing GridProfile/GridFitting local
        % Gaussian-dip fit, then fit a robust global 2D polynomial surface
        % across all refined points. This means one bad local fit can no
        % longer corrupt its neighbors (the old row/column/plane-to-plane
        % propagation), and the smooth global surface exploits the fact that
        % a real grid varies gradually across the field of view to average
        % out local noise. GridProfile/GridFitting/GridPositionOptimize
        % themselves are reused completely unmodified.

        function [latticeModel, status] = GridAutoDetectLattice(app, refImage)
            % Automatically detect the grid lattice (spacing, orientation and
            % phase) directly from the image via 2D FFT peak-picking plus a
            % projected-profile phase fit -- no manual clicks needed.
            % status: 1 = confident detection, 0 = ambiguous (caller should
            % confirm/nudge), -1 = failed (caller should fall back to manual
            % seeding).

            latticeModel = struct( ...
                'd1',NaN, 'd2',NaN, 'theta1',NaN, 'theta2',NaN, ...
                'u1',[NaN NaN], 'u2',[NaN NaN], 'origin_xy',[NaN NaN], ...
                'originIndex',[ceil(app.grid_SizeX/2) ceil(app.grid_SizeY/2)], ...
                'detectStatus',-1, 'confidence',0);
            status = -1;

            imageData = double(refImage);
            [imgH, imgW] = size(imageData);

            % 1. Auto-crop to the illuminated field
            fieldMask = imageData > 0;
            fieldMask = imclose(fieldMask, strel('disk',15));
            fieldMask = imfill(fieldMask,'holes');
            regionStats = regionprops(fieldMask, 'BoundingBox','Area');
            if isempty(regionStats)
                return;
            end
            [~, biggest] = max([regionStats.Area]);
            bbox = regionStats(biggest).BoundingBox;

            cropCx = bbox(1)+bbox(3)/2;
            cropCy = bbox(2)+bbox(4)/2;
            halfSize = floor(min(bbox(3),bbox(4))*0.42);
            if halfSize < 32
                return; % illuminated field too small to resolve a lattice
            end
            x0 = max(1, round(cropCx-halfSize));
            y0 = max(1, round(cropCy-halfSize));
            x1 = min(imgW, round(cropCx+halfSize)-1);
            y1 = min(imgH, round(cropCy+halfSize)-1);
            crop = imageData(y0:y1, x0:x1);
            N = min(size(crop));
            crop = crop(1:N,1:N);

            % 2. High-pass + windowed 2D FFT magnitude
            blurred = imgaussfilt(crop, 15);
            hp = crop - blurred;
            w = 0.5*(1-cos(2*pi*(0:N-1)/(N-1)));   %manual raised-cosine window
                                                    %(Signal Processing Toolbox's hann is not licensed here)
            win2d = w(:) * w;
            Fmag = abs(fftshift(fft2(hp .* win2d)));
            cy2 = floor(N/2)+1;
            cx2 = floor(N/2)+1;
            [XX,YY] = meshgrid(1:N,1:N);
            Rf = sqrt((XX-cx2).^2+(YY-cy2).^2);
            Fmag(Rf<6) = 0;

            % 3. Greedily pick candidate peaks, keep one of each conjugate pair
            Ftmp = Fmag;
            cand = zeros(0,3);
            for k=1:8
                [peakVal,ind] = max(Ftmp(:));
                if peakVal<=0
                    break;
                end
                [py,px] = ind2sub(size(Ftmp), ind);
                if px-cx2 > 0 || (px-cx2==0 && py-cy2>0)
                    cand(end+1,:) = [px-cx2, py-cy2, peakVal]; %#ok<AGROW>
                end
                Ftmp(max(1,py-5):min(N,py+5), max(1,px-5):min(N,px+5)) = 0;
            end
            if size(cand,1) < 2
                return;
            end

            % 4. Exclude harmonics (|f| ~ 2x a smaller candidate) and keep the
            % two smallest-|f| remaining as the fundamental lattice frequencies
            magf = hypot(cand(:,1), cand(:,2));
            [magf,order] = sort(magf);
            cand = cand(order,:);
            keep = true(size(magf));
            for i=1:numel(magf)
                if ~keep(i), continue; end
                for j=i+1:numel(magf)
                    if ~keep(j), continue; end
                    if abs(magf(j)/magf(i)-2) < 0.15
                        keep(j) = false;
                    end
                end
            end
            cand = cand(keep,:);
            if size(cand,1) < 2
                return;
            end
            f1 = cand(1,1:2);
            f2 = cand(2,1:2);

            d1 = N/hypot(f1(1),f1(2));
            th1 = atan2(f1(2),f1(1));
            d2 = N/hypot(f2(1),f2(2));
            th2 = atan2(f2(2),f2(1));

            % Canonicalize axis labeling & handedness so the same physical
            % direction gets called "axis 1"/"axis 2" from run to run: label
            % the more-horizontal direction as axis 1 (grid X), point it
            % generally rightward, and keep axis 2 within 180deg
            % counter-clockwise of it. This does NOT force exact
            % orthogonality -- the true (possibly non-90-degree) measured
            % angle between the axes is preserved, since that is real
            % distortion signal the later warp needs to correct.
            if abs(cos(th2)) > abs(cos(th1))
                [d1,d2] = deal(d2,d1);
                [th1,th2] = deal(th2,th1);
            end
            if cos(th1) < 0
                th1 = th1 + pi;
            end
            angleDiff = mod(th2-th1, 2*pi);
            if angleDiff > pi
                th2 = th2 + pi;
            end
            u1 = [cos(th1) sin(th1)];
            u2 = [cos(th2) sin(th2)];

            angleBetweenDeg = mod((th2-th1)*180/pi, 180);
            confidence = 1;
            if angleBetweenDeg < 30 || angleBetweenDeg > 150
                confidence = 0; % axes not clearly distinguishable
            end
            if d1 < 8 || d2 < 8 || d1 > N/2 || d2 > N/2
                confidence = 0;
            end

            % 5. Phase/origin: project the raw crop onto each axis and fit the
            % resulting periodic profile with the existing GridFitting model
            [t1_0, okT1] = GridProjectedPhase(app, crop, u1, d1);
            [t2_0, okT2] = GridProjectedPhase(app, crop, u2, d2);
            if ~okT1 || ~okT2
                confidence = min(confidence,0);
            end

            originCrop = [N/2, N/2] + t1_0*u1 + t2_0*u2;
            originFull = originCrop + [x0-1, y0-1];

            latticeModel.d1 = d1;
            latticeModel.d2 = d2;
            latticeModel.theta1 = th1*180/pi;
            latticeModel.theta2 = th2*180/pi;
            latticeModel.u1 = u1;
            latticeModel.u2 = u2;
            latticeModel.origin_xy = originFull;
            latticeModel.confidence = confidence;

            if confidence >= 1
                status = 1;
            else
                status = 0;
            end
            latticeModel.detectStatus = status;
        end

        function [offset, ok] = GridProjectedPhase(app, crop, u, d)
            % Project the crop intensity onto axis direction u and locate
            % the sub-pixel phase of the periodic grid-line dip, reusing
            % the existing GridFitting Gaussian-dip model (temporarily
            % widening its search range to +/- d/2 since this profile
            % spans a full grid period, unlike the narrow local
            % refinement search).
            %
            % The profile is built by FOLDING (synchronously averaging)
            % every period spanned by the crop into one -- not just the
            % single period nearest the crop center. Under weak signal
            % (low counts / low contrast, e.g. a defocused plane), a
            % single-period estimate is noisy enough to occasionally lock
            % onto the WRONG grid line, which then shifts every predicted
            % crossing in the whole plane by a full period -- an error
            % that looks perfectly self-consistent once baked in.
            % Validated on real data: under simulated weak-signal
            % conditions, folding cut the median phase error from ~3px to
            % 0px versus the single-period estimate.
            N = size(crop,1);
            [Xc,Yc] = meshgrid(1:size(crop,2), 1:size(crop,1));
            xr = Xc - N/2;
            yr = Yc - N/2;
            t = xr*u(1) + yr*u(2);
            tFolded = mod(t + d/2, d) - d/2;
            ti = round(tFolded);
            halfWin = floor(d/2);
            tRange = -halfWin:halfWin;
            profile = nan(size(tRange));
            for k=1:numel(tRange)
                m = (ti==tRange(k));
                if any(m(:))
                    profile(k) = mean(crop(m));
                end
            end
            valid = isfinite(profile);
            if nnz(valid) < numel(profile)*0.5
                offset = 0;
                ok = false;
                return;
            end
            profile(~valid) = median(profile(valid));

            savedFitRange = app.fitRange;
            savedDebugPlot = app.debugPlot;
            app.fitRange = max(savedFitRange, halfWin);
            app.debugPlot = false;  % GridFitting's debug plot assumes num is 1 or 2
                                     % (a slot in the local per-point 1x3 subplot
                                     % layout); this whole-profile phase fit doesn't
                                     % belong in that layout, so skip it here.
            [offset, ok, ~] = GridFitting(app, profile, 1);
            app.fitRange = savedFitRange;
            app.debugPlot = savedDebugPlot;
        end

        function latticeModel = GridLatticeFromManualPoints(app)
            % Wrap the legacy manually-clicked 4-corner seeding
            % (grid_Init_Position/FindGridSpacing) into the same
            % latticeModel struct used by the automatic path, so downstream
            % code is agnostic to how the lattice was obtained.
            FindGridSpacing(app);

            pos1 = app.grid_Position(1,:,1);
            pos2 = app.grid_Position(2,:,1);
            posY = app.grid_Position(app.grid_SizeX+1,:,1);

            [xdx, xdy] = GridPositionFindOffset(app, pos1, pos2);
            [ydx, ydy] = GridPositionFindOffset(app, pos1, posY);

            d1 = hypot(xdx,xdy);
            d2 = hypot(ydx,ydy);

            latticeModel.d1 = d1;
            latticeModel.d2 = d2;
            latticeModel.theta1 = atan2(xdy,xdx)*180/pi;
            latticeModel.theta2 = atan2(ydy,ydx)*180/pi;
            latticeModel.u1 = [xdx xdy]/d1;
            latticeModel.u2 = [ydx ydy]/d2;
            latticeModel.origin_xy = pos1;
            latticeModel.originIndex = [1 1];
            latticeModel.detectStatus = 1;
            latticeModel.confidence = 1;
        end

        function latticeModel = GridShowLatticeOverlay(app, latticeModel)
            % Show the predicted lattice over the reference image for a
            % single visual confirm (or coarse nudge), instead of clicking
            % all 4 corners.
            figHandle = figure(1);
            set(figHandle, 'NumberTitle', 'off', 'Name', 'Confirm Auto-Detected Grid');
            imshow(app.ref_Image, [0 3]);
            hold on;
            for grid_y = 1:app.grid_SizeY
                for grid_x = 1:app.grid_SizeX
                    pos = GridPredictPosition(app, latticeModel, grid_x, grid_y);
                    plot(pos(1), pos(2), 'y+', 'MarkerSize', 8, 'LineWidth', 1);
                end
            end
            hold off;

            fig = app.ImageCorrectionDialogUIFigure;
            msg = sprintf(['Auto-detected grid: spacing %.1f x %.1fpx, rotation %.1f/%.1fdeg.\n' ...
                'This is a ROUGH preview (a single rigid grid, not yet corrected for lens ' ...
                'distortion) -- it is expected to drift away from the true crossings toward ' ...
                'the edges; fine per-crossing fitting happens in the next step ' ...
                '("Populate First Plane"). Only check that the spacing/rotation/region ' ...
                'roughly match the real grid, not that every crossing lines up exactly.'], ...
                latticeModel.d1, latticeModel.d2, latticeModel.theta1, latticeModel.theta2);
            selection = uiconfirm(fig, msg, 'Confirm Grid Detection', ...
                'Options', {'Looks good','Nudge origin','Use manual points instead'}, ...
                'DefaultOption', 1);

            switch selection
                case 'Nudge origin'
                    % Let the user drag one anchor point from the predicted
                    % origin to the true nearest crossing; shift the whole
                    % lattice by that offset -- a single interaction, not 4.
                    anchorPos = GridPredictPosition(app, latticeModel, ...
                        latticeModel.originIndex(1), latticeModel.originIndex(2));
                    anchorHandle = drawpoint('Position', anchorPos, 'Color', [1 1 0]);
                    uiwait(msgbox('Drag the yellow point onto the nearest true grid crossing, then close this dialog.','Nudge origin'));
                    latticeModel.origin_xy = latticeModel.origin_xy + ...
                        (anchorHandle.Position - anchorPos);
                    delete(anchorHandle);
                case 'Use manual points instead'
                    latticeModel.detectStatus = -1;
            end
        end

        function pos = GridPredictPosition(app, latticeModel, gridX, gridY)
            % Predict a crossing's position purely from the global lattice
            % model -- never from a neighbor's fitted result -- which is
            % what stops one bad local fit from propagating to the rest.
            index = (gridY-1)*app.grid_SizeX + gridX;
            if isfield(latticeModel,'predictedPos')
                pos = latticeModel.predictedPos(index,:);
            else
                pos = latticeModel.origin_xy ...
                    + (gridX-latticeModel.originIndex(1))*latticeModel.d1*latticeModel.u1 ...
                    + (gridY-latticeModel.originIndex(2))*latticeModel.d2*latticeModel.u2;
            end
        end

        function [dx, dy] = GridLatticeLocalSpacing(app, latticeModel, gridX, gridY, dir)
            % Spacing/orientation vector used only to orient the local
            % Gaussian-dip search patch (GridProfile/GridPositionOptimize).
            index = (gridY-1)*app.grid_SizeX + gridX;
            if isfield(latticeModel,'localSpacing')
                v = latticeModel.localSpacing{dir}(index,:);
                dx = v(1);
                dy = v(2);
            elseif dir==1
                dx = latticeModel.d1*latticeModel.u1(1);
                dy = latticeModel.d1*latticeModel.u1(2);
            else
                dx = latticeModel.d2*latticeModel.u2(1);
                dy = latticeModel.d2*latticeModel.u2(2);
            end
        end

        function GridPopulatePlaneAuto(app, plane, latticeModel)
            % Populate every grid crossing on the given plane from the
            % supplied global lattice model, refining each point
            % INDEPENDENTLY (no neighbor-to-neighbor propagation) via the
            % existing GridPositionOptimize/GridProfile/GridFitting local
            % Gaussian-dip fit.
            for grid_y=1:app.grid_SizeY
                for grid_x=1:app.grid_SizeX
                    index=(grid_y-1)*app.grid_SizeX+grid_x;

                    estimatedPos = GridPredictPosition(app, latticeModel, grid_x, grid_y);
                    [xdx, xdy] = GridLatticeLocalSpacing(app, latticeModel, grid_x, grid_y, 1);

                    [newPos, fitStatus, fitQuality] = GridPositionOptimize(app, estimatedPos, xdx, xdy);

                    if fitStatus == 1
                        app.grid_Info{index, plane}.rawPos = newPos;
                        app.grid_Info{index, plane}.fitOK = true;
                    else
                        app.grid_Info{index, plane}.rawPos = estimatedPos;
                        app.grid_Info{index, plane}.fitOK = false;
                        newPos = estimatedPos;
                    end
                    app.grid_Info{index, plane}.fitRMSE = fitQuality;
                    app.grid_Info{index, plane}.source = 'lattice';
                    app.grid_Info{index, plane}.status = double(fitStatus==1);

                    app.grid_Position(index,:,plane) = newPos;
                end
            end
        end

        function [fitStatus, rmseX, rmseY] = GridFitSmoothSurface(app, plane)
            % Fit a robust global 2D polynomial surface X(gridX,gridY),
            % Y(gridX,gridY) across every independently-refined crossing on
            % this plane, then use it as the FINAL position for every
            % point -- this is what lets one bad/noisy local fit be
            % corrected by the rest of the grid instead of propagating.
            fitStatus = 0;
            rmseX = NaN;
            rmseY = NaN;

            nTotal = app.grid_SizeX*app.grid_SizeY;
            gx = zeros(nTotal,1);
            gy = zeros(nTotal,1);
            for grid_y=1:app.grid_SizeY
                for grid_x=1:app.grid_SizeX
                    index=(grid_y-1)*app.grid_SizeX+grid_x;
                    gx(index)=grid_x;
                    gy(index)=grid_y;
                end
            end

            goodMask = false(nTotal,1);
            rawXY = nan(nTotal,2);
            for index=1:nTotal
                info = app.grid_Info{index, plane};
                if isfield(info,'fitOK') && info.fitOK
                    goodMask(index) = true;
                    rawXY(index,:) = info.rawPos;
                end
            end

            nGood = nnz(goodMask);
            if nGood < 6
                return; % not enough points to fit any surface -- leave grid_Position as-is
            elseif nGood < 15
                surfaceOrder = 'poly11';
            elseif nGood < 30
                surfaceOrder = 'poly22';
            else
                surfaceOrder = 'poly23';
            end

            warnState = warning('off','curvefit:fit:iterationLimitReached'); % benign on very clean data
            sfX = fit([gx(goodMask) gy(goodMask)], rawXY(goodMask,1), surfaceOrder, 'Robust','Bisquare');
            sfY = fit([gx(goodMask) gy(goodMask)], rawXY(goodMask,2), surfaceOrder, 'Robust','Bisquare');
            warning(warnState);

            predX = sfX(gx,gy);
            predY = sfY(gx,gy);

            residX = rawXY(goodMask,1) - predX(goodMask);
            residY = rawXY(goodMask,2) - predY(goodMask);
            resid = hypot(residX, residY);
            outlierThresh = max(3, 3*1.4826*mad(resid,1));

            for index=1:nTotal
                app.grid_Position(index,:,plane) = [predX(index) predY(index)];
                if goodMask(index)
                    r = hypot(rawXY(index,1)-predX(index), rawXY(index,2)-predY(index));
                    app.grid_Info{index,plane}.surfaceResidual = r;
                    if r > outlierThresh
                        app.grid_Info{index,plane}.status = 2; %outlier
                    else
                        app.grid_Info{index,plane}.status = 1; %good
                    end
                else
                    app.grid_Info{index,plane}.surfaceResidual = NaN;
                    app.grid_Info{index,plane}.status = 1; %filled in from the smooth trend
                    app.grid_Info{index,plane}.source = 'surface';
                end
            end

            app.grid_SurfaceModel{plane} = struct('sfX',sfX,'sfY',sfY, ...
                'order',surfaceOrder,'nPointsUsed',nGood,'qualityFraction',nGood/nTotal);

            rmseX = sqrt(mean(residX.^2));
            rmseY = sqrt(mean(residY.^2));
            fitStatus = 1;
        end

        function latticeModel = GridLatticeFromSurface(app, plane)
            % Build a position-dependent local spacing/direction estimate
            % from the fitted surface's local gradient (central finite
            % differences in grid-index space), used to re-seed one extra
            % refinement pass for points near the field edge where the
            % single global lattice model is a poorer local approximation.
            % Still purely a function of the smooth global model, not of
            % any neighbor's raw fit.
            surfaceModel = app.grid_SurfaceModel{plane};

            gx = repmat((1:app.grid_SizeX)', app.grid_SizeY, 1);
            gy = reshape(repmat(1:app.grid_SizeY, app.grid_SizeX, 1), [], 1);

            predX = surfaceModel.sfX(gx, gy);
            predY = surfaceModel.sfY(gx, gy);

            dx1 = (surfaceModel.sfX(gx+1,gy) - surfaceModel.sfX(gx-1,gy))/2;
            dy1 = (surfaceModel.sfY(gx+1,gy) - surfaceModel.sfY(gx-1,gy))/2;
            dx2 = (surfaceModel.sfX(gx,gy+1) - surfaceModel.sfX(gx,gy-1))/2;
            dy2 = (surfaceModel.sfY(gx,gy+1) - surfaceModel.sfY(gx,gy-1))/2;

            latticeModel = app.grid_LatticeModel{plane};
            latticeModel.predictedPos = [predX predY];
            latticeModel.localSpacing = {[dx1 dy1], [dx2 dy2]};
        end

        function GridCheckPlaneContinuity(app, plane)
            % Non-blocking sanity check (NOT a propagation dependency):
            % warn if this plane's detected lattice differs sharply from
            % the previous plane's, since the physical grid should drift
            % only gradually across an eV/focus series.
            if plane < 2 || numel(app.grid_LatticeModel) < plane ...
                    || isempty(app.grid_LatticeModel{plane-1}) || isempty(app.grid_LatticeModel{plane})
                return;
            end
            prevModel = app.grid_LatticeModel{plane-1};
            curModel = app.grid_LatticeModel{plane};

            spacingChange1 = abs(curModel.d1-prevModel.d1)/prevModel.d1;
            spacingChange2 = abs(curModel.d2-prevModel.d2)/prevModel.d2;
            angleChange1 = abs(curModel.theta1-prevModel.theta1);
            angleChange2 = abs(curModel.theta2-prevModel.theta2);

            if spacingChange1>0.05 || spacingChange2>0.05 || angleChange1>2 || angleChange2>2
                warning('GridCorrection:PlaneContinuity', ...
                    ['Plane %d''s detected lattice differs notably from plane %d ' ...
                     '(spacing change %.1f%%/%.1f%%, angle change %.1f/%.1fdeg). ' ...
                     'Consider visually re-checking this plane.'], ...
                    plane, plane-1, spacingChange1*100, spacingChange2*100, angleChange1, angleChange2);
            end
        end

        function latticeModel = GridAlignPlanePhase(app, plane, latticeModel)
            % Correct a systematic "off by N grid periods" phase error.
            % FFT-based spacing/orientation detection is essentially
            % unaffected by blur or low contrast (it depends on
            % periodicity, not phase), but the phase/origin step
            % (GridProjectedPhase) can occasionally lock onto the wrong
            % grid line under those conditions -- shifting EVERY
            % predicted crossing in the plane by the same integer number
            % of periods (e.g. "the whole grid offset by a row"). This
            % looks perfectly self-consistent within the plane -- nothing
            % looks like an outlier -- so it can only be caught by
            % comparing against a neighboring, already-processed plane.
            if plane < 2
                return;
            end
            refModel = [];
            for prevPlane = plane-1:-1:1
                if numel(app.grid_LatticeModel) >= prevPlane && ~isempty(app.grid_LatticeModel{prevPlane})
                    % Prefer a previous plane that also had good local fit
                    % quality as the reference, but fall back to whatever
                    % is available if none qualifies.
                    goodRef = numel(app.grid_SurfaceModel) >= prevPlane && ...
                        ~isempty(app.grid_SurfaceModel{prevPlane}) && ...
                        isfield(app.grid_SurfaceModel{prevPlane},'qualityFraction') && ...
                        app.grid_SurfaceModel{prevPlane}.qualityFraction >= 0.5;
                    if isempty(refModel) || goodRef
                        refModel = app.grid_LatticeModel{prevPlane};
                    end
                    if goodRef
                        break;
                    end
                end
            end
            if isempty(refModel)
                return;
            end

            % Express the origin discrepancy in lattice-basis (period)
            % coordinates: an integer result means the phase locked onto
            % the wrong grid line by that many periods.
            A = [latticeModel.d1*latticeModel.u1(:), latticeModel.d2*latticeModel.u2(:)];
            offset = (latticeModel.origin_xy - refModel.origin_xy)';
            periodCoords = A \ offset;
            nearestInt = round(periodCoords);
            residual = periodCoords - nearestInt;

            if any(nearestInt ~= 0) && norm(residual) < 0.3
                correction = (A * nearestInt)';
                latticeModel.origin_xy = latticeModel.origin_xy - correction;
                warning('GridCorrection:PhaseAligned', ...
                    ['Plane %d''s detected grid phase was off by [%d, %d] whole periods ' ...
                     'from the reference plane -- corrected automatically.'], ...
                    plane, nearestInt(1), nearestInt(2));
            end
        end

        function GridOptimizePlaneUntilConverged(app, plane, latticeModel, maxPasses)
            % Repeatedly predict->refine->smooth this plane, re-seeding
            % each pass from the previous pass's smooth-surface
            % prediction, until the number of genuinely well-fit
            % crossings stops improving (or nearly every point has
            % converged) -- rather than a fixed pass count. This is
            % exactly what manually clicking "Optimize This Plane" a
            % couple more times used to be needed for (a few scattered
            % points converge only on a later pass, once their search
            % window is centered from an already-improved surface
            % rather than the raw/first-pass estimate); now it happens
            % automatically, bounded by maxPasses so it can't loop
            % forever on a plane that will never fully converge.
            nTotal = app.grid_SizeX*app.grid_SizeY;
            prevGoodCount = -1;
            for pass = 1:maxPasses
                if pass == 1
                    GridPopulatePlaneAuto(app, plane, latticeModel);
                else
                    refinedModel = GridLatticeFromSurface(app, plane);
                    GridPopulatePlaneAuto(app, plane, refinedModel);
                end
                GridFitSmoothSurface(app, plane);

                nGoodNow = 0;
                for index = 1:nTotal
                    infoIdx = app.grid_Info{index, plane};
                    if isstruct(infoIdx) && isfield(infoIdx,'status') && infoIdx.status==1 ...
                            && isfield(infoIdx,'fitOK') && infoIdx.fitOK
                        nGoodNow = nGoodNow + 1;
                    end
                end
                if nGoodNow <= prevGoodCount || nGoodNow >= nTotal*0.98
                    break; % no more improvement, or effectively fully converged
                end
                prevGoodCount = nGoodNow;
            end
        end

        function GridPlaceRawLatticePositions(app, plane, latticeModel)
            % Skip local refinement/smoothing entirely -- just place each
            % crossing at its raw predicted lattice position. Used when
            % the user has unchecked "Optimize Grid Crossings" for a fast,
            % un-refined preview.
            for grid_y=1:app.grid_SizeY
                for grid_x=1:app.grid_SizeX
                    index=(grid_y-1)*app.grid_SizeX+grid_x;
                    pos = GridPredictPosition(app, latticeModel, grid_x, grid_y);
                    app.grid_Position(index,:,plane) = pos;
                    app.grid_Info{index,plane}.rawPos = pos;
                    app.grid_Info{index,plane}.fitOK = false;
                    app.grid_Info{index,plane}.status = 0;
                    app.grid_Info{index,plane}.source = 'lattice-raw';
                end
            end
        end

        function latticeModel = GridLatticeFromCurrentPositions(app, plane)
            % Build a lattice-model-shaped seed from whatever is CURRENTLY
            % in grid_Position for this plane -- the raw lattice guess, a
            % prior optimization pass, or manually-nudged points (dragged
            % via the interactive display) -- so "Optimize This Plane" can
            % be run on demand, any time, regardless of how the plane got
            % its current positions.
            nTotal = app.grid_SizeX*app.grid_SizeY;
            predictedPos = zeros(nTotal,2);
            for grid_y=1:app.grid_SizeY
                for grid_x=1:app.grid_SizeX
                    index=(grid_y-1)*app.grid_SizeX+grid_x;
                    predictedPos(index,:) = app.grid_Position(index,:,plane);
                end
            end

            if numel(app.grid_SurfaceModel) >= plane && ~isempty(app.grid_SurfaceModel{plane})
                % Prefer the locally-accurate, surface-gradient-based
                % spacing/direction for orienting each point's search
                % patch; only its localSpacing is used here, not its
                % (possibly stale) predictedPos, so manual nudges are
                % respected.
                surfaceLattice = GridLatticeFromSurface(app, plane);
                localSpacing = surfaceLattice.localSpacing;
            elseif numel(app.grid_LatticeModel) >= plane && ~isempty(app.grid_LatticeModel{plane})
                base = app.grid_LatticeModel{plane};
                localSpacing = { ...
                    repmat(base.d1*base.u1, nTotal, 1), ...
                    repmat(base.d2*base.u2, nTotal, 1)};
            else
                error('GridCorrection:NoLatticeModel', ...
                    'No lattice model available for plane %d; run Setup Grid Points / Populate first.', plane);
            end

            latticeModel.predictedPos = predictedPos;
            latticeModel.localSpacing = localSpacing;
        end

        function GridCloseFigures(~)
            % Close every plain (non-uifigure) figure window this app may
            % have created -- grid display (1), fitting-debug plots (2),
            % correction-test before/after views (2/3) -- so none are
            % left orphaned however the app gets closed. Uses findall
            % (never creates a figure, unlike calling figure(N) directly)
            % and tolerates figures that no longer exist.
            for figNum = 1:3
                figHandles = findall(groot, 'Type', 'figure', 'Number', figNum);
                for k = 1:numel(figHandles)
                    try
                        delete(figHandles(k));
                    catch
                        % already gone -- nothing to do
                    end
                end
            end
        end

        function GridFitZSmoothing(app)
            % Correlate grid-crossing positions ACROSS Z planes: the
            % physical grid doesn't change shape between planes -- only
            % the sample stage/optics drift slowly across the eV/focus
            % series -- so a plane whose own measurement was poor (e.g.
            % blurry) can be corrected using the trend from planes that
            % measured well.
            %
            % Two failure modes this guards against (both seen in
            % practice): (1) an unweighted fit across all planes lets
            % noisy/bad planes pull an already well-determined plane's
            % position away from its own good measurement; (2) a
            % systematically bad plane (e.g. every point in a blurry
            % plane is off in a similar way) doesn't look like a
            % point-level outlier WITHIN that plane, so within-plane
            % checks alone don't catch it.
            %
            % Fix: only TRUSTED planes -- ones with a genuine converged
            % local measurement (source=='lattice', fitOK), not flagged
            % an outlier by the within-plane surface fit (status~=2), AND
            % from a plane whose own auto-detected lattice was confident
            % -- are used to fit the cross-plane trend. Only NON-trusted
            % planes are then replaced by that trend; a trusted plane's
            % own directly-measured position is never touched.
            nX = app.grid_SizeX;
            nY = app.grid_SizeY;
            nZ = app.grid_SizeZ;
            nTotal = nX*nY;

            if nZ < 3 || numel(app.grid_Info) < nTotal*nZ
                return; % not enough planes to fit any meaningful Z trend
            end

            planeConfidence = ones(nZ,1);
            planeQuality = ones(nZ,1);
            for z = 1:nZ
                if numel(app.grid_LatticeModel) >= z && ~isempty(app.grid_LatticeModel{z}) ...
                        && isfield(app.grid_LatticeModel{z},'confidence')
                    planeConfidence(z) = app.grid_LatticeModel{z}.confidence;
                end
                if numel(app.grid_SurfaceModel) >= z && ~isempty(app.grid_SurfaceModel{z}) ...
                        && isfield(app.grid_SurfaceModel{z},'qualityFraction')
                    % Fraction of this plane's crossings that had a
                    % genuine converged local fit -- a low fraction
                    % (e.g. a blurry/low-contrast plane where most points
                    % failed and had to be filled in from its own
                    % within-plane surface fit) means this plane shouldn't
                    % be trusted as a reference for correcting others,
                    % even if no single point looks like an outlier.
                    planeQuality(z) = app.grid_SurfaceModel{z}.qualityFraction;
                end
            end

            zIndex = (1:nZ)';
            minTrusted = 3;

            for index = 1:nTotal
                xVals = zeros(nZ,1);
                yVals = zeros(nZ,1);
                populated = false(nZ,1);
                trusted = false(nZ,1);
                for z = 1:nZ
                    info = app.grid_Info{index, z};
                    if ~isstruct(info) || ~isfield(info,'source')
                        continue;
                    end
                    populated(z) = true;
                    xVals(z) = app.grid_Position(index,1,z);
                    yVals(z) = app.grid_Position(index,2,z);

                    isGenuineMeasurement = strcmp(info.source,'lattice') && ...
                        isfield(info,'fitOK') && info.fitOK;
                    isNotOutlier = ~isfield(info,'status') || info.status ~= 2;
                    trusted(z) = isGenuineMeasurement && isNotOutlier && ...
                        planeConfidence(z) >= 1 && planeQuality(z) >= 0.5;
                end

                nTrusted = nnz(trusted);
                if nTrusted < minTrusted
                    continue; % not enough trustworthy planes to establish a
                              % trend for this point -- leave every plane's
                              % own value exactly as it was
                end

                zOrder = 'poly1';
                if nTrusted >= 6
                    zOrder = 'poly2';
                end

                warnState = warning('off','curvefit:fit:iterationLimitReached'); % benign on very clean data
                sfXz = fit(zIndex(trusted), xVals(trusted), zOrder, 'Robust','Bisquare');
                sfYz = fit(zIndex(trusted), yVals(trusted), zOrder, 'Robust','Bisquare');
                warning(warnState);

                for z = 1:nZ
                    % Only replace planes that were populated but NOT
                    % trusted -- a trusted plane's own measurement is left
                    % untouched, and an unpopulated plane is left as-is.
                    if trusted(z) || ~populated(z)
                        continue;
                    end
                    app.grid_Position(index,:,z) = [sfXz(z) sfYz(z)];
                    app.grid_Info{index,z}.zSmoothed = true;
                    app.grid_Info{index,z}.source = 'z-trend';
                    app.grid_Info{index,z}.status = 1;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if(nargin>1)
                app.parentApp=varargin{1};
            end
            app.workingDirectory=pwd;


        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            [filename,filepath]=uiputfile('*.mat','Save Current settings');
            if filename == 0
                return;
            end

            filename=[filepath filename];
            app.filename_CurrentSettings=filename;
            
            Parameters.fig_Grid_InitHandleReady=app.fig_Grid_InitHandleReady;
            Parameters.grid_Init_Position=app.grid_Init_Position;

            Parameters.grid_SizeX=app.grid_SizeX;
            Parameters.grid_SizeY=app.grid_SizeY;
            Parameters.grid_SizeZ=app.grid_SizeZ;
            Parameters.grid_PlaneStart=app.grid_PlaneStart;
            Parameters.grid_PlaneAveraging=app.grid_PlaneAveraging;
            Parameters.grid_PlaneInterval=app.grid_PlaneInterval;
            Parameters.grid_Position=app.grid_Position;
            Parameters.grid_Info=app.grid_Info;
            Parameters.grid_SettingStatus=app.grid_SettingStatus;
            Parameters.grid_TransformationMethod=app.TransformationMethodDropDown.Value;
            Parameters.filename_GridRef=app.filename_GridRef;
            Parameters.filename_Flatfield=app.filename_Flatfield;
            Parameters.filename_CurrentSettings=app.filename_CurrentSettings;
            Parameters.filename_GeometricDistortion=app.filename_GeometricDistortion;
            Parameters.applyFF=app.ApplyFFCheckBox.Value;
            Parameters.applyGridDistortionCorrection=app.ApplyGCCheckBox.Value;
            Parameters.applyObjectiveDistortionCorrection=app.ApplyDCCheckBox.Value;

            Parameters.image_OutputShiftX=app.OutputShiftXEditField.Value;
            Parameters.image_OutputShiftY=app.OutputShiftYEditField.Value;

            Parameters.grid_LatticeModel=app.grid_LatticeModel;
            Parameters.grid_AutoDetectEnabled=app.grid_AutoDetectEnabled;
            Parameters.optimizeGridCrossings=app.OptimizeGridCheckBox.Value;
            Parameters.grid_RefinementPasses=app.grid_RefinementPasses;

            save(filename, 'Parameters');
            ChangeFileNameLabel(app, app.FilenameCurrentSettingsLabel, app.filename_CurrentSettings);

        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
          if app.debug==0
                [filename,filepath]=uigetfile('*.mat','Load old settings');
                if filename == 0
                    return;
                end
          else
                filepath= 'Distortion Grid\';
                filename= 'GC_20221219v2.mat';
          end

            filename=[filepath filename];
            app.filename_CurrentSettings=filename;
            
          try
            load(filename, 'Parameters');
            
            app.fig_Grid_InitHandleReady=Parameters.fig_Grid_InitHandleReady;
            app.grid_Init_Position=Parameters.grid_Init_Position;
            app.grid_SizeX=Parameters.grid_SizeX;
            app.grid_SizeY=Parameters.grid_SizeY;
            app.grid_SizeZ=Parameters.grid_SizeZ;
            app.grid_PlaneStart=Parameters.grid_PlaneStart;
            app.grid_PlaneAveraging=Parameters.grid_PlaneAveraging;
            app.grid_PlaneInterval=Parameters.grid_PlaneInterval;
            app.grid_Position=Parameters.grid_Position;
            app.grid_Info=Parameters.grid_Info;
            app.grid_SettingStatus=Parameters.grid_SettingStatus;
            app.TransformationMethodDropDown.Value=Parameters.grid_TransformationMethod;
            app.filename_GridRef=Parameters.filename_GridRef;
            app.filename_Flatfield=Parameters.filename_Flatfield;
            app.filename_CurrentSettings=Parameters.filename_CurrentSettings;
            app.filename_GeometricDistortion=Parameters.filename_GeometricDistortion;
            app.ApplyFFCheckBox.Value=Parameters.applyFF;
            app.ApplyGCCheckBox.Value=Parameters.applyGridDistortionCorrection;
            app.ApplyDCCheckBox.Value=Parameters.applyObjectiveDistortionCorrection;
            app.OutputShiftXEditField.Value=Parameters.image_OutputShiftX;
            app.OutputShiftYEditField.Value=Parameters.image_OutputShiftY;

            if isfield(Parameters,'grid_LatticeModel')
                app.grid_LatticeModel=Parameters.grid_LatticeModel;
            else
                app.grid_LatticeModel={};
            end
            if isfield(Parameters,'grid_AutoDetectEnabled')
                app.grid_AutoDetectEnabled=Parameters.grid_AutoDetectEnabled;
            else
                app.grid_AutoDetectEnabled=true;
            end
            if isfield(Parameters,'optimizeGridCrossings')
                app.OptimizeGridCheckBox.Value=Parameters.optimizeGridCrossings;
            else
                app.OptimizeGridCheckBox.Value=true;
            end
            if isfield(Parameters,'grid_RefinementPasses')
                app.grid_RefinementPasses=Parameters.grid_RefinementPasses;
            else
                app.grid_RefinementPasses=2;
            end
          catch
                 app.filename_CurrentSettings='Invalid file!';
          end
            app.GridSizeXEditField.Value=app.grid_SizeX;
            app.GridSizeYEditField.Value=app.grid_SizeY;
            app.GridSizeZEditField.Value=app.grid_SizeZ;   
            app.GridPlaneStartEditField.Value=app.grid_PlaneStart;
            app.GridPlaneAveragingEditField.Value=app.grid_PlaneAveraging;
            app.GridPlaneIntervalEditField.Value=app.grid_PlaneInterval;
            SetGridPlaneParameter(app);

            ChangeFileNameLabel(app, app.FilenameGridRefLabel, app.filename_GridRef);
            ChangeFileNameLabel(app, app.FilenameFlatFieldLabel, app.filename_Flatfield);
            ChangeFileNameLabel(app, app.FilenameCurrentSettingsLabel, app.filename_CurrentSettings);
            ChangeFileNameLabel(app, app.FilenameGerometricDistortionLabel, app.filename_GeometricDistortion);
            GridSettingStatusChange(app, app.grid_SettingStatus);
            app.grid_PlaneCurrent=1;
            app.GridCurrentLabel.Text=sprintf("%d / %d", app.grid_PlaneCurrent, app.GridSizeZEditField.Value);

            
        end

        % Close request function: ImageCorrectionDialogUIFigure
        function ImageCorrectionDialogUIFigureCloseRequest(app, event)
            
            if(app.grid_SettingStatus)
                fig=app.ImageCorrectionDialogUIFigure;
                msg='Save settings before closing?';
                title='Closing application';
                selection=uiconfirm(fig, msg, title, 'Options', {'Yes', 'No', 'Cancel'}, 'DefaultOption', 1);
                
                switch(selection)
                    case 'Yes'
                        SaveSettingsButtonPushed(app);
                    case 'No'
                        %no nothing
                    case 'Cancel'
                        return;
                end
            end
            
            if isobject(app.parentApp)
                app.parentApp.ImagePostProcessing.Enable = 'on';
            end

            GridCloseFigures(app);
            delete(app);
        end

        % Button pushed function: ChangeFlatfieldImageButton
        function ChangeFlatfieldImageButtonPushed(app, event)
            [ff_filename,ff_filepath]=uigetfile('*.tiff;*.tif','Choose a flatfield image');
            if ff_filename == 0
                return;
            end
            
            app.filename_Flatfield=[ff_filepath ff_filename];
            ChangeFileNameLabel(app, app.FilenameFlatFieldLabel, app.filename_Flatfield);
            
        end

        % Button pushed function: ChangeRefImageButton
        function ChangeRefImageButtonPushed(app, event)
            if app.debug==0
                [filename,filepath]=uigetfile('*.tiff;*.tif','Choose a reference image');
                if filename == 0
                    return;
                end
            else
                filename= 'GC 20260212_1_30kcps.tiff';
                filepath= 'C:\Users\micha\OneDrive - OIST\Experiments\2026 Twisted WSe2\GC\';
            end
            app.filename_GridRef=[filepath filename];
            ChangeFileNameLabel(app, app.FilenameGridRefLabel, app.filename_GridRef);
            GridSettingStatusChange(app, app.grid_SettingStatus)
            

        end

        % Button pushed function: ChangeObjDistortionButton
        function ChangeObjDistortionButtonPushed(app, event)
            [filename,filepath]=uigetfile('*.mat','Choose Lens Distortion Correction Reference');
            if filename == 0
                return;
            end
            
            filename=[filepath filename];
            app.filename_GeometricDistortion=filename;
            ChangeFileNameLabel(app,app.FilenameGerometricDistortionLabel,filename)
        end

        % Button pushed function: CorrectImagesButton
        function CorrectImagesButtonPushed(app, event)
            if(app.ApplyFFCheckBox.Value==false && app.ApplyGCCheckBox.Value==false && app.ApplyDCCheckBox.Value==false)
                return;
            end
            
            filepath=uigetdir('*.*','Choose folder for image correction');
            if filepath == 0
                return;
            end
            
            ProcessDirectory=filepath;
            files=dir(fullfile(ProcessDirectory,strcat('*.','tiff')));
            processImages=files;
            
            filename=[ProcessDirectory filesep processImages(1).name];
            file_info = imfinfo(filename);
            app.img_SizeZ = numel(file_info);
            nImages=length(processImages);

            app.ref_Image=imread(filename, 1);
            app.ref_Image=single(app.ref_Image);
            
            
            %% Setup Flatfieldimage
            if(app.ApplyFFCheckBox.Value==true)
                FFImage=imread(app.filename_Flatfield, 1);
                FFImage=single(FFImage);
                %select center region and calculate average intensity there
                FFImageSize=size(FFImage);
                CropFFImage=FFImage(FFImageSize(1)*0.25:FFImageSize(1)*0.75, FFImageSize(1)*0.25:FFImageSize(2)*0.75);
                AvgInt=sum(sum(CropFFImage))/size(CropFFImage,1)/size(CropFFImage,2);
                %Normalize the Flatfield image
                FFImage=AvgInt./FFImage;
                FFImage(FFImage==Inf)=0;
            end
            
            %% Setup Grid Distortion Correction
            if(app.ApplyGCCheckBox.Value==true && app.grid_SettingStatus==4)
                
                Z=zeros(1, app.grid_SizeZ);
                for iGrid=1:app.grid_SizeZ
                    Z(iGrid)=app.grid_PlaneStart+(iGrid-1)*app.grid_PlaneInterval;
                end
                
                % interpolate grid position for all slides
                Zz=1:1:app.img_SizeZ;
                grid_Position_Spline=spline(Z, app.grid_Position, Zz);
                
                [RA, worldPoints, squareSize]=GridTransformationInitialize(app, 1);  % Setup output world positions
            end
            
            if(app.ApplyDCCheckBox.Value==true)
                load(app.filename_GeometricDistortion,"-mat",'lens_distortion_ref_K',...
                    'd');
                K=lens_distortion_ref_K;
                reference_slice_number=lens_distortion_ref_K{1}(:,4);
                
                polyOrder=2; % What Order polynomial to use for interpolating the reference points
                Kx_fitting=cell(6,1);
                Ky_fitting=cell(6,1);
                interpolatedPoints=zeros(6,2,app.img_SizeZ); % Will use interpolated points of the
                firstOrderPoly=@(z,m,b)m.*z+b;
                secondOrderPoly=@(z,a,b,c)a.*z.^2+b.*z+c;
                
                for K_Point_Number=1:6
                    if(polyOrder==1)
                        Kx_fitting{K_Point_Number}=polyfit(reference_slice_number,K{K_Point_Number}(:,1),1);
                        Ky_fitting{K_Point_Number}=polyfit(reference_slice_number,K{K_Point_Number}(:,2),1);
                        
                        interpolatedPoints(K_Point_Number,1,:)=...
                            firstOrderPoly(1:app.img_SizeZ,Kx_fitting{K_Point_Number}(1),Kx_fitting{K_Point_Number}(2));
                        interpolatedPoints(K_Point_Number,2,:)=...
                            firstOrderPoly(1:app.img_SizeZ,Ky_fitting{K_Point_Number}(1),Ky_fitting{K_Point_Number}(2));
                        %                         referencePoints(K_Point_Number,:)=K{K_Point_Number}(3,:);
                    elseif(polyOrder==2)
                        Kx_fitting{K_Point_Number}=polyfit(reference_slice_number,K{K_Point_Number}(:,1),2);
                        Ky_fitting{K_Point_Number}=polyfit(reference_slice_number,K{K_Point_Number}(:,2),2);
                        
                        interpolatedPoints(K_Point_Number,1,:)=...
                            secondOrderPoly(1:app.img_SizeZ,Kx_fitting{K_Point_Number}(1),Kx_fitting{K_Point_Number}(2),Kx_fitting{K_Point_Number}(3));
                        interpolatedPoints(K_Point_Number,2,:)=...
                            secondOrderPoly(1:app.img_SizeZ,Ky_fitting{K_Point_Number}(1),Ky_fitting{K_Point_Number}(2),Ky_fitting{K_Point_Number}(3));
                        %                         referencePoints(K_Point_Number,:)=K{K_Point_Number}(3,:);
                    end
                end
                
                sizeOutput=[900 900];
                d=mean(d);
                horizontalPadding=(sizeOutput(2)-d)/2;
                verticalPadding=(sizeOutput(1)-d*sqrt(3)/2)/2;
                referenceHexagon=[
                    horizontalPadding+1*(d/4),  verticalPadding;                % K1
                    horizontalPadding,          1*(d*sqrt(3)/4)+verticalPadding;% K2
                    horizontalPadding+1*(d/4),  2*(d*sqrt(3)/4)+verticalPadding;% K3
                    horizontalPadding+3*(d/4),  2*(d*sqrt(3)/4)+verticalPadding;% K4
                    horizontalPadding+4*(d/4),  1*(d*sqrt(3)/4)+verticalPadding;% K5
                    horizontalPadding+3*(d/4),  verticalPadding;                % K6
                    ];
                
                sizeOutput=[900 900];
                RA2=imref2d(sizeOutput,[1 900],[1 900]);
                
                switch app.StyleDropDown.Value
                    case 'STV 0, All Points'
                        DC_ref=[K{1}(K{1}(:,3,:)==0,1:2,:);
                            K{2}(K{2}(:,3,:)==0,1:2,:);
                            K{3}(K{3}(:,3,:)==0,1:2,:);
                            K{4}(K{4}(:,3,:)==0,1:2,:);
                            K{5}(K{5}(:,3,:)==0,1:2,:);
                            K{6}(K{6}(:,3,:)==0,1:2,:)];
                    case 'STV 0, 4 points'
                        DC_ref=[K{2}(K{2}(:,3,:)==0,1:2,:);
                            K{3}(K{3}(:,3,:)==0,1:2,:);
                            K{5}(K{5}(:,3,:)==0,1:2,:);
                            K{6}(K{6}(:,3,:)==0,1:2,:)];
                        interpolatedPoints=interpolatedPoints([2,3,5,6],:,:);
                    case 'Force Hexagon'
                        DC_ref=referenceHexagon;
                end
                
            end
            
            fig=app.ImageCorrectionDialogUIFigure;
            title='Please Wait';
            dlg=uiprogressdlg(fig, 'Title', title, 'Cancelable','on');
            
            for iImage=1:nImages
                
                msg=sprintf('Processing image %d / %d', iImage, nImages);
                loop_i_percent=(iImage-1)/nImages;
                dlg.Value=loop_i_percent;
                dlg.Message=msg;
                
                OutputDirectory = [ProcessDirectory filesep 'Processed'];
                if not(isfolder(OutputDirectory))
                    mkdir(OutputDirectory)
                end
                
                Input_filename=[ProcessDirectory filesep processImages(iImage).name];
                Output_filename=[OutputDirectory filesep processImages(iImage).name];
                
                filename_prefix=Output_filename;
                idx = find(ismember(filename_prefix,'.'),1,'last');
                if filename_prefix(idx) == '.'
                    filename_prefix(idx:end) = [];
                end
                
                filename_Flatfield_suffix='';
                filename_GC_suffix='';
                filename_DC_suffix='';
                
                if(app.ApplyFFCheckBox.Value==true)
                    filename_Flatfield_suffix='_FF';
                end
                
                if(app.ApplyGCCheckBox.Value==true)
                    filename_GC_suffix='_GC';
                end
                
                if(app.ApplyDCCheckBox.Value==true)
                    filename_DC_suffix='_DC';
                end
                
                filename_suffix=[filename_Flatfield_suffix filename_GC_suffix filename_DC_suffix];
                t = Tiff([filename_prefix filename_suffix '.tiff'],'w');
                
                for iZ=1:app.img_SizeZ
                    loop_j_percent=1/nImages*(iZ/app.img_SizeZ);
                    dlg.Value=loop_i_percent+loop_j_percent;
                    
                    if dlg.CancelRequested
                        dlg.Message=sprintf('Finishing current image...');
                    end
                    
                    LocalrawImage=imread(Input_filename, iZ);
                    LocalrawImage=single(LocalrawImage);
                    
                    if(app.ApplyFFCheckBox.Value==true)
                        LocalrawImage=LocalrawImage.*FFImage;
                    end
                    
                    if(app.ApplyGCCheckBox.Value==true  && app.grid_SettingStatus==4)

                        transformationMethod=str2double(app.TransformationMethodDropDown.Value);
                        switch(transformationMethod)
                            case 1
                                tform = fitgeotrans(grid_Position_Spline(:,:,iZ),worldPoints,"affine");
                            case 2
                                tform = fitgeotrans(grid_Position_Spline(:,:,iZ),worldPoints,"projective");
                            case 3
                                tform = fitgeotrans(grid_Position_Spline(:,:,iZ),worldPoints,"polynomial", 3);
                            case 4
                                tform = fitgeotrans(grid_Position_Spline(:,:,iZ),worldPoints,"polynomial", 4);
                        end

                        if(transformationMethod<5)
                            correctedImage=imwarp(LocalrawImage, tform, 'Outputview', RA, 'interp', 'nearest'); %nearest, linear or cubic
                            OutputImage=round(correctedImage,2);
                        else
                            %code for piecewise correction
                            %m,n position in mini maps; i,j positions in world maps
            
                            miniOutputPosition=zeros(9,2);
                            miniGridPosition=zeros(9,2);
            
                            mini_ImageSize=2*squareSize;
                            RA_mini = imref2d([mini_ImageSize mini_ImageSize]);
                
                            miniWorldPoints = generateCheckerboardPoints([4 4], squareSize);
                            miniWorldPoints = flip(miniWorldPoints,2);  %exchange x and y axis in world points
                            
                            iL=floor((app.grid_SizeX-1)/2);
                            jL=floor((app.grid_SizeY-1)/2);
            
                            correctedImage=zeros(mini_ImageSize*jL, mini_ImageSize*iL);
                            grid_Position_Current=grid_Position_Spline(:,:,iZ);
                            
                            for j=1:jL
                                for i=1:iL
                                    i_current=1+(i-1)*2;
                                    j_current=1+(j-1)*2;
                                    for n=1:3
                                        for m=1:3
                                            index_mini=m+(n-1)*3;
                                            index_world=i_current+m-1+(j_current+n-2)*app.grid_SizeX;
                                            miniOutputPosition(index_mini,:)=worldPoints(index_world,:);
                                            miniGridPosition(index_mini,:)=grid_Position_Current(index_world,:);
                                        end
                                    end
                
                                    xOutput = [miniOutputPosition(1,2) miniOutputPosition(end,2)];
                                    yOutput = [miniOutputPosition(1,1) miniOutputPosition(end,1)];
            
                                    switch(transformationMethod)
                                        case 5
                                            tform = fitgeotrans(miniGridPosition,miniWorldPoints,"affine");
                                        case 6
                                            tform = fitgeotrans(miniGridPosition,miniWorldPoints,"projective");
                                        case 7
                                            tform = fitgeotrans(miniGridPosition,miniWorldPoints,"polynomial", 2);
            
                                    end
            
                                    correctedminiImage=imwarp(LocalrawImage, tform, 'interp', 'cubic', 'Outputview', RA_mini); %nearest, linear or cubic
                                    correctedImage((xOutput(1)+1):xOutput(2), (yOutput(1)+1):yOutput(2))=correctedminiImage;
                                end
                            end
                            OutputImage=round(correctedImage,2);
                        end
                    else
                        OutputImage=LocalrawImage;
                    end
                    
                    if(app.ApplyDCCheckBox.Value==true)
                        tMatrix=fitgeotrans(interpolatedPoints(:,:,iZ),DC_ref,'affine');  
                        correctedImage=imwarp(OutputImage, tMatrix, 'Outputview', RA2,'interp', 'nearest'); 
                        OutputImage=round(correctedImage,2);
                    end
                    
                    setTag(t,'Photometric',Tiff.Photometric.MinIsBlack);
                    setTag(t,'PlanarConfiguration',Tiff.PlanarConfiguration.Chunky);
                    setTag(t,'Orientation',Tiff.Orientation.TopLeft);
                    setTag(t,'BitsPerSample',32);
                    setTag(t,'SamplesPerPixel',1);
                    setTag(t,'Compression', Tiff.Compression.LZW);
                    setTag(t,'ImageLength',size(OutputImage,1));
                    setTag(t,'ImageWidth',size(OutputImage,2));
                    setTag(t,'SampleFormat', Tiff.SampleFormat.IEEEFP);
                    write(t, single(OutputImage));
                    
                    writeDirectory(t);
                    
                end
                close(t);
                if dlg.CancelRequested
                    break
                end
            end
            close(dlg);
            
        end

        % Button pushed function: PopulateFirstPlaneButton
        function PopulateFirstPlaneButtonPushed(app, event)
            app.grid_PlaneCurrent=1;
            ChangeRefImagePlane(app);

            fig=app.ImageCorrectionDialogUIFigure;
            title='Please Wait';
            dlg=uiprogressdlg(fig, 'Title', title, 'Cancelable','off');
            msg=sprintf('Finding grid positions for the first plane');
            dlg.Message=msg;

            tic;
            latticeModel = app.grid_LatticeModel{1};
            if app.OptimizeGridCheckBox.Value
                GridOptimizePlaneUntilConverged(app, 1, latticeModel, max(app.grid_RefinementPasses,4));
            else
                GridPlaceRawLatticePositions(app, 1, latticeModel);
            end

            figure(1);
            imshow(app.ref_Image, [0 3]);
            for index=1:app.grid_SizeX*app.grid_SizeY
                switch(app.grid_Info{index, app.grid_PlaneCurrent}.status)
                    case 1
                        app.fig_GridHandle{index}.handle = drawpoint('Position',app.grid_Position(index,:, app.grid_PlaneCurrent));                    
                    case 2
                        app.fig_GridHandle{index}.handle = drawpoint('Position',app.grid_Position(index,:, app.grid_PlaneCurrent), 'Color', [1 0.55 0.75]);                    
                    otherwise
                        app.fig_GridHandle{index}.handle = drawpoint('Position',app.grid_Position(index,:, app.grid_PlaneCurrent), 'Color', [1 0.2 0.75]);                    
                end
                addlistener(app.fig_GridHandle{index}.handle,'ROIMoved', @(varargin)GridPositionMoved(app, varargin));
                app.fig_GridHandle{index}.handle.Tag=int2str(index);
            end
            toc;
           GridSettingStatusChange(app, 3);
           close(dlg);
            
        end

        % Button pushed function: SetupGridPointsButton
        function SetupGridPointsButtonPushed(app, event)
            app.grid_PlaneCurrent=1;
            ChangeRefImagePlane(app);

            fig=app.ImageCorrectionDialogUIFigure;
            title='Please Wait';
            dlg=uiprogressdlg(fig, 'Title', title, 'Cancelable','off');
            msg=sprintf('Detecting grid lattice');
            dlg.Message=msg;

            useManual = ~app.grid_AutoDetectEnabled;
            latticeModel = [];
            if ~useManual
                [latticeModel, autoStatus] = GridAutoDetectLattice(app, app.ref_Image);
                if autoStatus >= 0
                    latticeModel = GridShowLatticeOverlay(app, latticeModel);
                end
                if autoStatus < 0 || latticeModel.detectStatus < 0
                    useManual = true;
                end
            end

            if useManual
                figure(1);
                imshow(app.ref_Image, [0 1]);

                if(app.debug==true && app.fig_Grid_InitHandleReady==false)
                    app.grid_Init_Position=zeros(4,2);
                    app.grid_Init_Position(1,:)=[233  340];
                    app.grid_Init_Position(2,:)=[236  380];
                    app.grid_Init_Position(3,:)=[277  373];
                    app.grid_Init_Position(4,:)=[277  332];
                    app.fig_Grid_InitHandleReady=true;
                end

                if(app.fig_Grid_InitHandleReady==false)
                    app.grid_Init_Position=zeros(4,2);
                    for i=1:4
                        app.fig_Grid_InitHandle{i}.handle = drawpoint;
                        try
                            app.fig_Grid_InitHandle{i}.handle.Tag=int2str(i);
                            addlistener(app.fig_Grid_InitHandle{i}.handle,'ROIMoved', @(varargin)GridInitPositionMoved(app, varargin));
                        catch
                            warning('Grid points are missing');
                            close(dlg);
                            return;
                        end
                        app.grid_Init_Position(i,:) = app.fig_Grid_InitHandle{i}.handle.Position;
                    end
                    app.fig_Grid_InitHandleReady=true;
                else
                    for i=1:4
                        app.fig_Grid_InitHandle{i}.handle = drawpoint("Position",app.grid_Init_Position(i,:));
                        app.fig_Grid_InitHandle{i}.handle.Tag=int2str(i);
                        addlistener(app.fig_Grid_InitHandle{i}.handle,'ROIMoved', @(varargin)GridInitPositionMoved(app, varargin));
                    end
                end

                latticeModel = GridLatticeFromManualPoints(app);
            end

            app.grid_LatticeModel{1} = latticeModel;

           GridSettingStatusChange(app, 2);
           close(dlg);

        end

        % Value changed function: GridPlaneStartEditField
        function GridPlaneStartEditFieldValueChanged(app, event)
            app.grid_PlaneStart=app.GridPlaneStartEditField.Value;
            SetGridPlaneParameter(app);
        end

        % Value changed function: GridSizeZEditField
        function GridSizeZEditFieldValueChanged(app, event)
            app.grid_SizeZ=app.GridSizeZEditField.Value;
            SetGridPlaneParameter(app);

        end

        % Value changed function: GridPlaneIntervalEditField
        function GridPlaneIntervalEditFieldValueChanged(app, event)
            app.grid_PlaneInterval=app.GridPlaneIntervalEditField.Value;
            SetGridPlaneParameter(app);
            
        end

        % Button pushed function: InitialiseButton
        function InitialiseButtonPushed(app, event)
            GridInitialization(app);
            LoadReferenceImage(app);
            GridSettingStatusChange(app, 1);
            app.GridCurrentLabel.Text=sprintf("%d / %d", app.grid_PlaneCurrent, app.GridSizeZEditField.Value);

        end

        % Button pushed function: PopulatetherestButton
        function PopulatetherestButtonPushed(app, event)
            fig=app.ImageCorrectionDialogUIFigure;
            title='Grid Optimization';
            dlg=uiprogressdlg(fig, 'Title', title, 'Cancelable','on');

            for i=2:app.GridSizeZEditField.Value
                msg=sprintf('Working on Grid Plane %d / %d', i, app.GridSizeZEditField.Value);
                progress_bar=i/(app.GridSizeZEditField.Value);
                dlg.Value=progress_bar;
                dlg.Message=msg;

                app.grid_PlaneCurrent=i;

                ChangeRefImagePlane(app);

                tic;
                % Each plane's lattice is detected independently (rather than
                % propagated from the previous plane's fitted points), so a
                % bad fit on one plane cannot corrupt every later plane.
                warnState = warning('off','backtrace'); % keep the message, drop the noisy call-stack lines
                [latticeModel, autoStatus] = GridAutoDetectLattice(app, app.ref_Image);
                if autoStatus < 0
                    latticeModel = app.grid_LatticeModel{i-1};
                    warning('GridCorrection:AutoDetectFailed', ...
                        ['Automatic grid detection failed on plane %d; reusing plane %d''s ' ...
                         'lattice. Please visually re-check this plane.'], i, i-1);
                else
                    % Guard against a whole-plane "off by N grid periods"
                    % phase error (e.g. a defocused/low-contrast plane
                    % locking onto the wrong grid line) before it gets
                    % baked into every crossing on this plane.
                    latticeModel = GridAlignPlanePhase(app, i, latticeModel);
                end
                app.grid_LatticeModel{i} = latticeModel;
                GridCheckPlaneContinuity(app, i);
                warning(warnState);

                if app.OptimizeGridCheckBox.Value
                    GridOptimizePlaneUntilConverged(app, i, latticeModel, max(app.grid_RefinementPasses,4));
                else
                    GridPlaceRawLatticePositions(app, i, latticeModel);
                end
                toc;
                if dlg.CancelRequested
                    break
                end
            end

            if app.OptimizeGridCheckBox.Value && ~dlg.CancelRequested
                % Correlate crossing positions across Z planes (mirrors the
                % within-plane XY smoothing above) now that every plane has
                % been independently populated and refined.
                dlg.Message = 'Correlating grid positions across planes (Z)';
                GridFitZSmoothing(app);
            end

            close(dlg);
            % Return to plane 1 so the displayed count matches the still-
            % displayed figure (the figure isn't redrawn here), and refresh
            % it to show plane 1's final, fully-processed positions.
            app.grid_PlaneCurrent = 1;
            app.GridCurrentLabel.Text=sprintf("%d / %d", app.grid_PlaneCurrent, app.GridSizeZEditField.Value);
            GridSettingStatusChange(app, 4);
            GridChangeDisplayPlane(app);


        end

        % Button pushed function: OptimizePlaneButton
        function OptimizePlaneButtonPushed(app, event)
            % Re-run optimization (independent local refinement + robust
            % global surface fit) on whichever plane is currently
            % displayed -- available any time a plane has been populated,
            % regardless of whether "Optimize Grid Crossings" was checked
            % when it was first populated, and regardless of any manual
            % nudges made since. Seeded from the CURRENT positions, so
            % running it repeatedly performs additional refinement passes.
            plane = app.grid_PlaneCurrent;

            fig = app.ImageCorrectionDialogUIFigure;
            dlg = uiprogressdlg(fig, 'Title', 'Please Wait', ...
                'Message', sprintf('Optimizing plane %d', plane), 'Cancelable','off');

            ChangeRefImagePlane(app);

            try
                latticeModel = GridLatticeFromCurrentPositions(app, plane);
            catch ME
                close(dlg);
                uialert(fig, ME.message, 'Cannot Optimize Plane');
                return;
            end

            GridOptimizePlaneUntilConverged(app, plane, latticeModel, max(app.grid_RefinementPasses,4));

            GridChangeDisplayPlane(app);
            close(dlg);
        end

        % Button pushed function: GridDisplayFirstPlane
        function GridDisplayFirstPlaneButtonPushed(app, event)
            app.grid_PlaneCurrent=1;
            app.GridCurrentLabel.Text=sprintf("%d / %d", app.grid_PlaneCurrent, app.GridSizeZEditField.Value);
            GridChangeDisplayPlane(app);
        end

        % Button pushed function: GridDisplayLastPlane
        function GridDisplayLastPlaneButtonPushed(app, event)
            app.grid_PlaneCurrent=app.GridSizeZEditField.Value;
            app.GridCurrentLabel.Text=sprintf("%d / %d", app.grid_PlaneCurrent, app.GridSizeZEditField.Value);
            GridChangeDisplayPlane(app);
        end

        % Button pushed function: GridDisplayPrevPlane
        function GridDisplayPrevPlaneButtonPushed(app, event)
            app.grid_PlaneCurrent=app.grid_PlaneCurrent-1;
            if(app.grid_PlaneCurrent<1) 
                app.grid_PlaneCurrent=1;
            end
            app.GridCurrentLabel.Text=sprintf("%d / %d", app.grid_PlaneCurrent, app.GridSizeZEditField.Value);
            GridChangeDisplayPlane(app);
        end

        % Button pushed function: GridDisplayNextPlane
        function GridDisplayNextPlaneButtonPushed(app, event)
            app.grid_PlaneCurrent=app.grid_PlaneCurrent+1;
            if(app.grid_PlaneCurrent>app.GridSizeZEditField.Value)
                app.grid_PlaneCurrent=app.GridSizeZEditField.Value;
            end
            app.GridCurrentLabel.Text=sprintf("%d / %d", app.grid_PlaneCurrent, app.GridSizeZEditField.Value);
            GridChangeDisplayPlane(app);
        end

        % Value changed function: GridPlaneAveragingEditField
        function GridPlaneAveragingEditFieldValueChanged(app, event)
            app.grid_PlaneAveraging = app.GridPlaneAveragingEditField.Value;
            
        end

        % Value changed function: GridSizeXEditField
        function GridSizeXEditFieldValueChanged(app, event)
            app.grid_SizeX = app.GridSizeXEditField.Value;
            
        end

        % Value changed function: GridSizeYEditField
        function GridSizeYEditFieldValueChanged(app, event)
            app.grid_SizeY = app.GridSizeYEditField.Value;
            
        end

        % Button pushed function: CorrectionTestButton
        function CorrectionTestButtonPushed(app, event)
            DistortionCorrectionTest(app);
        end

        % Button pushed function: ResetSettingsButton
        function ResetSettingsButtonPushed(app, event)
            GridSettingStatusChange(app, 0);

        end

        % Callback function
        function PiecewiseCorrectionTestButtonPushed(app, event)

            ChangeRefImagePlane(app);
            
            grid_Position_Current=app.grid_Position(:,:,app.grid_PlaneCurrent);
            squareSize = norm(grid_Position_Current(1,:)-grid_Position_Current(app.grid_SizeX,:))/(app.grid_SizeX-1);
            squareSize = ceil(squareSize);
            worldPoints = generateCheckerboardPoints([app.grid_SizeX+1 app.grid_SizeY+1], squareSize);
            worldPoints = flip(worldPoints,2);  %exchange x and y axis in world points
            
            %yWorldLimits = [0 (app.grid_SizeY-1)*squareSize];
            %xWorldLimits = [0 (app.grid_SizeX-1)*squareSize];
            %RA = imref2d([(app.grid_SizeX-1)*squareSize (app.grid_SizeY-1)*squareSize],xWorldLimits,yWorldLimits);

            %code for piecewise correction
            %m,n position in mini maps; i,j positions in world maps
            miniOutputPosition=zeros(9,2);
            miniGridPosition=zeros(9,2);

            miniWorldPoints = generateCheckerboardPoints([4 4], squareSize);
            miniWorldPoints = flip(miniWorldPoints,2);  %exchange x and y axis in world points

            iL=floor(app.grid_SizeX/2);
            jL=floor(app.grid_SizeY/2);


            for j=1:jL
                for i=1:iL
                    i_current=1+(i-1)*2;
                    j_current=1+(j-1)*2;
                    for n=1:3
                        for m=1:3
                            index_mini=m+(n-1)*3;
                            index_world=i_current+m-1+(j_current+n-2)*app.grid_SizeX;
                            miniOutputPosition(index_mini,:)=worldPoints(index_world,:);
                            miniGridPosition(index_mini,:)=grid_Position_Current(index_world,:);
                        end
                    end

                    index=i+(j-1)*iL;
                    app.ref_Image=insertText(app.ref_Image, [miniGridPosition(1,1)+10 miniGridPosition(1,2)+10], index, "TextColor","white", "FontSize", 20, 'BoxOpacity',0);
                    app.ref_Image=app.ref_Image(:,:,1);

                    xOutput = [miniOutputPosition(1,2) miniOutputPosition(end,2)];
                    yOutput = [miniOutputPosition(1,1) miniOutputPosition(end,1)];
                    mini_ImageSize=2*squareSize;
                    RA_mini = imref2d([mini_ImageSize mini_ImageSize]);
        
                    tform = fitgeotrans(miniGridPosition,miniWorldPoints,"affine");
                    correctedminiImage=imwarp(app.ref_Image, tform, 'interp', 'cubic', 'Outputview', RA_mini); %nearest, linear or cubic

                    figure(3);
                    subplot(iL, jL, i+(j-1)*iL);
                    imshow(correctedminiImage, [0 3]);

                    ccc((xOutput(1)+1):xOutput(2), (yOutput(1)+1):yOutput(2))=correctedminiImage;
                end
            end


            figure(2);
            subplot(1,2,1);
            imshow(app.ref_Image, [0 3]);
            title('Before Correction');
            subplot(1,2,2);
            imshow(ccc, [0 3]);
            title('After Correction');
            
        end

        % Value changed function: OutputShiftXEditField
        function OutputShiftXEditFieldValueChanged(app, event)
            app.image_OutputShiftX = app.OutputShiftXEditField.Value;
            
        end

        % Value changed function: OutputShiftYEditField
        function OutputShiftYEditFieldValueChanged(app, event)
            app.image_OutputShiftY = app.OutputShiftYEditField.Value;

        end

        % Value changed function: ShowFittingFigureCheckBox
        function ShowFittingFigureCheckBoxValueChanged(app, event)
            app.debugPlot = app.ShowFittingFigureCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageCorrectionDialogUIFigure = uifigure('Visible', 'off');
            app.ImageCorrectionDialogUIFigure.Name = 'Image Correction Dialog';
            app.ImageCorrectionDialogUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImageCorrectionDialogUIFigureCloseRequest, true);
            app.GridDistortionSettingsPanel = uipanel(app.ImageCorrectionDialogUIFigure);
            app.GridDistortionSettingsPanel.Title = 'Grid Distortion Settings';
            app.OptimizeGridCheckBox = uicheckbox(app.GridDistortionSettingsPanel);
            app.OptimizeGridCheckBox.Text = 'Optimize Grid Crossings (fit + smooth)';
            app.OptimizeGridCheckBox.Value = true;
            app.OptimizePlaneButton = uibutton(app.GridDistortionSettingsPanel, 'push');
            app.OptimizePlaneButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizePlaneButtonPushed, true);
            app.OptimizePlaneButton.Tooltip = {'Re-run local fitting + global smoothing on the currently displayed plane, using its current crossing positions (including any manual nudges) as the starting point.'};
            app.OptimizePlaneButton.Enable = 'off';
            app.OptimizePlaneButton.Text = 'Optimize This Plane';
            app.ShowFittingFigureCheckBox = uicheckbox(app.GridDistortionSettingsPanel);
            app.ShowFittingFigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFittingFigureCheckBoxValueChanged, true);
            app.ShowFittingFigureCheckBox.Tooltip = {'Show the per-crossing fitting plot (figure 2) while optimizing. Off by default -- it redraws on every single crossing, which is slow during batch population.'};
            app.ShowFittingFigureCheckBox.Text = 'Show Fitting Figure';
            app.ShowFittingFigureCheckBox.Value = false;
            app.GridSizeLabel = uilabel(app.GridDistortionSettingsPanel);
            app.GridSizeLabel.Text = 'Grid Size';
            app.GridImageLabel = uilabel(app.GridDistortionSettingsPanel);
            app.GridImageLabel.Text = 'Grid Image';
            app.FilenameGridRefLabel = uilabel(app.GridDistortionSettingsPanel);
            app.FilenameGridRefLabel.Text = 'none';
            app.ChangeRefImageButton = uibutton(app.GridDistortionSettingsPanel, 'push');
            app.ChangeRefImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeRefImageButtonPushed, true);
            app.ChangeRefImageButton.Text = 'Change';
            app.GridSizeXEditField = uieditfield(app.GridDistortionSettingsPanel, 'numeric');
            app.GridSizeXEditField.Limits = [1 50];
            app.GridSizeXEditField.ValueDisplayFormat = '%.0f';
            app.GridSizeXEditField.ValueChangedFcn = createCallbackFcn(app, @GridSizeXEditFieldValueChanged, true);
            app.GridSizeXEditField.Value = 10;
            app.xEditField_2Label = uilabel(app.GridDistortionSettingsPanel);
            app.xEditField_2Label.Text = 'x';
            app.GridSizeYEditField = uieditfield(app.GridDistortionSettingsPanel, 'numeric');
            app.GridSizeYEditField.Limits = [1 50];
            app.GridSizeYEditField.ValueDisplayFormat = '%.0f';
            app.GridSizeYEditField.ValueChangedFcn = createCallbackFcn(app, @GridSizeYEditFieldValueChanged, true);
            app.GridSizeYEditField.Value = 10;
            app.SlideLabel_2 = uilabel(app.GridDistortionSettingsPanel);
            app.SlideLabel_2.Text = 'Slide #';
            app.StartLabel = uilabel(app.GridDistortionSettingsPanel);
            app.StartLabel.Text = 'Start';
            app.GridPlaneStartEditField = uieditfield(app.GridDistortionSettingsPanel, 'numeric');
            app.GridPlaneStartEditField.Limits = [1 799];
            app.GridPlaneStartEditField.ValueDisplayFormat = '%.0f';
            app.GridPlaneStartEditField.ValueChangedFcn = createCallbackFcn(app, @GridPlaneStartEditFieldValueChanged, true);
            app.GridPlaneStartEditField.Value = 400;
            app.EndLabel = uilabel(app.GridDistortionSettingsPanel);
            app.EndLabel.Text = 'End';
            app.GridPlaneEndEditField = uieditfield(app.GridDistortionSettingsPanel, 'numeric');
            app.GridPlaneEndEditField.Limits = [1 10000];
            app.GridPlaneEndEditField.ValueDisplayFormat = '%.0f';
            app.GridPlaneEndEditField.Editable = 'off';
            app.GridPlaneEndEditField.Enable = 'off';
            app.GridPlaneEndEditField.Value = 800;
            app.AveragingEditFieldLabel = uilabel(app.GridDistortionSettingsPanel);
            app.AveragingEditFieldLabel.Text = 'Averaging';
            app.GridPlaneAveragingEditField = uieditfield(app.GridDistortionSettingsPanel, 'numeric');
            app.GridPlaneAveragingEditField.Limits = [1 100];
            app.GridPlaneAveragingEditField.ValueDisplayFormat = '%.0f';
            app.GridPlaneAveragingEditField.ValueChangedFcn = createCallbackFcn(app, @GridPlaneAveragingEditFieldValueChanged, true);
            app.GridPlaneAveragingEditField.Value = 10;
            app.NumberofplanesLabel = uilabel(app.GridDistortionSettingsPanel);
            app.NumberofplanesLabel.Text = 'Number of planes';
            app.GridSizeZEditField = uieditfield(app.GridDistortionSettingsPanel, 'numeric');
            app.GridSizeZEditField.Limits = [2 100];
            app.GridSizeZEditField.ValueDisplayFormat = '%.0f';
            app.GridSizeZEditField.ValueChangedFcn = createCallbackFcn(app, @GridSizeZEditFieldValueChanged, true);
            app.GridSizeZEditField.Value = 9;
            app.SlidesbetweensetsLabel = uilabel(app.GridDistortionSettingsPanel);
            app.SlidesbetweensetsLabel.Text = {'Slides between plane'; '(round to 10s)'};
            app.GridPlaneIntervalEditField = uieditfield(app.GridDistortionSettingsPanel, 'numeric');
            app.GridPlaneIntervalEditField.Limits = [10 100];
            app.GridPlaneIntervalEditField.ValueDisplayFormat = '%.0f';
            app.GridPlaneIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @GridPlaneIntervalEditFieldValueChanged, true);
            app.GridPlaneIntervalEditField.Value = 50;
            app.InitialiseButton = uibutton(app.GridDistortionSettingsPanel, 'push');
            app.InitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseButtonPushed, true);
            app.InitialiseButton.Enable = 'off';
            app.InitialiseButton.Text = '1. Initialise';
            app.SetupGridPointsButton = uibutton(app.GridDistortionSettingsPanel, 'push');
            app.SetupGridPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SetupGridPointsButtonPushed, true);
            app.SetupGridPointsButton.Enable = 'off';
            app.SetupGridPointsButton.Text = '2. Setup Grid Points';
            app.PopulateFirstPlaneButton = uibutton(app.GridDistortionSettingsPanel, 'push');
            app.PopulateFirstPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @PopulateFirstPlaneButtonPushed, true);
            app.PopulateFirstPlaneButton.Enable = 'off';
            app.PopulateFirstPlaneButton.Text = '3. Populate First Plane';
            app.PopulatetherestButton = uibutton(app.GridDistortionSettingsPanel, 'push');
            app.PopulatetherestButton.ButtonPushedFcn = createCallbackFcn(app, @PopulatetherestButtonPushed, true);
            app.PopulatetherestButton.Enable = 'off';
            app.PopulatetherestButton.Text = '4. Populate the rest...';
            app.GridDisplayFirstPlane = uibutton(app.GridDistortionSettingsPanel, 'push');
            app.GridDisplayFirstPlane.ButtonPushedFcn = createCallbackFcn(app, @GridDisplayFirstPlaneButtonPushed, true);
            app.GridDisplayFirstPlane.Enable = 'off';
            app.GridDisplayFirstPlane.Text = '|<<';
            app.GridDisplayLastPlane = uibutton(app.GridDistortionSettingsPanel, 'push');
            app.GridDisplayLastPlane.ButtonPushedFcn = createCallbackFcn(app, @GridDisplayLastPlaneButtonPushed, true);
            app.GridDisplayLastPlane.Enable = 'off';
            app.GridDisplayLastPlane.Text = '>>|';
            app.GridDisplayPrevPlane = uibutton(app.GridDistortionSettingsPanel, 'push');
            app.GridDisplayPrevPlane.ButtonPushedFcn = createCallbackFcn(app, @GridDisplayPrevPlaneButtonPushed, true);
            app.GridDisplayPrevPlane.Enable = 'off';
            app.GridDisplayPrevPlane.Text = '<';
            app.GridDisplayNextPlane = uibutton(app.GridDistortionSettingsPanel, 'push');
            app.GridDisplayNextPlane.ButtonPushedFcn = createCallbackFcn(app, @GridDisplayNextPlaneButtonPushed, true);
            app.GridDisplayNextPlane.Enable = 'off';
            app.GridDisplayNextPlane.Text = '>';
            app.CorrectionTestButton = uibutton(app.GridDistortionSettingsPanel, 'push');
            app.CorrectionTestButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectionTestButtonPushed, true);
            app.CorrectionTestButton.Enable = 'off';
            app.CorrectionTestButton.Text = 'Correction Test';
            app.GridCurrentLabel = uilabel(app.GridDistortionSettingsPanel);
            app.GridCurrentLabel.Text = '#/#';
            app.ResetSettingsButton = uibutton(app.GridDistortionSettingsPanel, 'push');
            app.ResetSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSettingsButtonPushed, true);
            app.ResetSettingsButton.Text = '0. Reset Settings';
            app.MethodDropDownLabel = uilabel(app.GridDistortionSettingsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.TransformationMethodDropDown = uidropdown(app.GridDistortionSettingsPanel);
            app.TransformationMethodDropDown.Items = {'affine', 'projective', 'polynomial (order 3)', 'polynomial (order 4)', 'piecewise-affine', 'piecewise-projective', 'piecewise-polynomial order 2'};
            app.TransformationMethodDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.TransformationMethodDropDown.Value = '1';
            app.ShiftOutputLabel = uilabel(app.GridDistortionSettingsPanel);
            app.ShiftOutputLabel.Text = 'Shift Output';
            app.OutputShiftXEditField = uieditfield(app.GridDistortionSettingsPanel, 'numeric');
            app.OutputShiftXEditField.Limits = [-1000 1000];
            app.OutputShiftXEditField.ValueDisplayFormat = '%.0f';
            app.OutputShiftXEditField.ValueChangedFcn = createCallbackFcn(app, @OutputShiftXEditFieldValueChanged, true);
            app.xEditField_2Label_2 = uilabel(app.GridDistortionSettingsPanel);
            app.xEditField_2Label_2.Text = 'x';
            app.OutputShiftYEditField = uieditfield(app.GridDistortionSettingsPanel, 'numeric');
            app.OutputShiftYEditField.Limits = [-1000 1000];
            app.OutputShiftYEditField.ValueDisplayFormat = '%.0f';
            app.OutputShiftYEditField.ValueChangedFcn = createCallbackFcn(app, @OutputShiftYEditFieldValueChanged, true);
            app.xEditField_2Label_3 = uilabel(app.GridDistortionSettingsPanel);
            app.xEditField_2Label_3.Text = 'pixels';
            app.ObjectiveDistortionSettingsPanel = uipanel(app.ImageCorrectionDialogUIFigure);
            app.ObjectiveDistortionSettingsPanel.Title = 'Objective Distortion Settings';
            app.ChangeObjDistortionButton = uibutton(app.ObjectiveDistortionSettingsPanel, 'push');
            app.ChangeObjDistortionButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeObjDistortionButtonPushed, true);
            app.ChangeObjDistortionButton.Text = 'Change';
            app.FilenameGerometricDistortionLabel = uilabel(app.ObjectiveDistortionSettingsPanel);
            app.FilenameGerometricDistortionLabel.Text = 'none';
            app.CurrentSettingsLabel_2 = uilabel(app.ObjectiveDistortionSettingsPanel);
            app.CurrentSettingsLabel_2.Text = 'Current Settings';
            app.StyleDropDownLabel = uilabel(app.ObjectiveDistortionSettingsPanel);
            app.StyleDropDownLabel.Text = 'Style';
            app.StyleDropDown = uidropdown(app.ObjectiveDistortionSettingsPanel);
            app.StyleDropDown.Items = {'STV 0, All Points', 'STV 0, 4 points', 'Force Hexagon'};
            app.StyleDropDown.Value = 'STV 0, All Points';
            app.FlatfieldImagePanel = uipanel(app.ImageCorrectionDialogUIFigure);
            app.FlatfieldImagePanel.Title = 'Flatfield Image';
            app.FilenameFlatFieldLabel = uilabel(app.FlatfieldImagePanel);
            app.FilenameFlatFieldLabel.Text = 'none';
            app.ChangeFlatfieldImageButton = uibutton(app.FlatfieldImagePanel, 'push');
            app.ChangeFlatfieldImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeFlatfieldImageButtonPushed, true);
            app.ChangeFlatfieldImageButton.Text = 'Change';
            app.CurrentFlatfieldimageLabel = uilabel(app.FlatfieldImagePanel);
            app.CurrentFlatfieldimageLabel.Text = 'Current Flatfield image';
            app.MainMenuPanel = uipanel(app.ImageCorrectionDialogUIFigure);
            app.MainMenuPanel.Title = 'Main Menu';
            app.CorrectImagesButton = uibutton(app.MainMenuPanel, 'push');
            app.CorrectImagesButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectImagesButtonPushed, true);
            app.CorrectImagesButton.Tooltip = {'Select folder to apply image distortion correction'};
            app.CorrectImagesButton.Text = 'Correct Images';
            app.ApplyFFCheckBox = uicheckbox(app.MainMenuPanel);
            app.ApplyFFCheckBox.Text = 'Apply FF';
            app.ApplyGCCheckBox = uicheckbox(app.MainMenuPanel);
            app.ApplyGCCheckBox.Text = 'Grid distortion';
            app.ApplyDCCheckBox = uicheckbox(app.MainMenuPanel);
            app.ApplyDCCheckBox.Text = 'Objective distortion';
            app.LoadSettingsButton = uibutton(app.MainMenuPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SaveSettingsButton = uibutton(app.MainMenuPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.FilenameCurrentSettingsLabel = uilabel(app.MainMenuPanel);
            app.FilenameCurrentSettingsLabel.Text = 'none';
            app.CurrentSettingsLabel = uilabel(app.MainMenuPanel);
            app.CurrentSettingsLabel.Text = 'Current Settings';
            % Show the figure after all components are created
            app.ImageCorrectionDialogUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImagePostProcessingDlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageCorrectionDialogUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Close any plain figure windows (grid display, fitting-debug
            % plots, correction-test views) this app may have created, so
            % none are left orphaned however the app gets closed.
            GridCloseFigures(app);

            % Delete UIFigure when app is deleted
            delete(app.ImageCorrectionDialogUIFigure)
        end
    end
end